classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        InicioMenu                 matlab.ui.container.Menu
        ShutdownMenu               matlab.ui.container.Menu
        TornilloButton             matlab.ui.control.Button
        TuercaButton               matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Lamp2                      matlab.ui.control.Lamp
        Lamp3                      matlab.ui.control.Lamp
        Lamp4                      matlab.ui.control.Lamp
        Lamp5                      matlab.ui.control.Lamp
        TCPLabel                   matlab.ui.control.Label
        VALORESARTICULARESLabel    matlab.ui.control.Label
        TORNILLOSLabel             matlab.ui.control.Label
        TUERCASLabel               matlab.ui.control.Label
        TCPLabel_2                 matlab.ui.control.Label
        VALORESARTICULARESLabel_2  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        STOPButton                 matlab.ui.control.Button
        STOPButton_2               matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        EditField                  matlab.ui.control.NumericEditField
        EditField2                 matlab.ui.control.NumericEditField
        EditField3                 matlab.ui.control.NumericEditField
        Label2                     matlab.ui.control.Label
        Label3                     matlab.ui.control.Label
        Label4                     matlab.ui.control.Label
        Label5                     matlab.ui.control.Label
        Label6                     matlab.ui.control.Label
        Label7                     matlab.ui.control.Label
        Label8                     matlab.ui.control.Label
        Label9                     matlab.ui.control.Label
        Label10                    matlab.ui.control.Label
        Label11                    matlab.ui.control.Label
        Label12                    matlab.ui.control.Label
        Label13                    matlab.ui.control.Label
        Label14                    matlab.ui.control.Label
        Label15                    matlab.ui.control.Label
        Label16                    matlab.ui.control.Label
        Button                     matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Button pushed function: TornilloButton
        function TornilloButtonPushed(app, event)

             app.Lamp.Color = 'y';
            addpath /home/adri/Documentos/Robotica/Proyecto/Proyecto_intermedio_robotica/03_scripts_matlab
           tornillo
            intento
            app.Lamp.Color = 'g';


        end

        % Menu selected function: InicioMenu
        function InicioMenuSelected(app, event)
        rosshutdown
        app.Lamp3.Color = 'r';
        rosinit
        addpath /home/adri/Documentos/Robotica/Proyecto/Proyecto_intermedio_robotica/03_scripts_matlab/RVC1/rvctools
        startup_rvc
        app.Lamp4.Color = 'y';
        addpath /home/adri/Documentos/Robotica/Proyecto/Proyecto_intermedio_robotica/03_scripts_matlab
        inicio
        app.Lamp2.Color = 'g';
        app.Lamp.Color = 'g';
        
        end

        % Callback function
        function ButtonPushed(app, event)
            numero = app.EditField.Value;
             app.VALORESARTICULARESLabel.Text = num2str(numero);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
             
        end

        % Button pushed function: TuercaButton
        function TuercaButtonPushed(app, event)
            app.Lamp2.Color = 'y';
            addpath /home/adri/Documentos/Robotica/Proyecto/Proyecto_intermedio_robotica/03_scripts_matlab
            tuerca
            app.Lamp2.Color = 'g';
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            addpath /home/adri/Documentos/Robotica/Proyecto/Proyecto_intermedio_robotica/03_scripts_matlab
            intento
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.InicioMenu = uimenu(app.Menu);
            app.InicioMenu.MenuSelectedFcn = createCallbackFcn(app, @InicioMenuSelected, true);
            app.InicioMenu.Text = 'Inicio';
            app.ShutdownMenu = uimenu(app.Menu);
            app.ShutdownMenu.Text = 'Shutdown';
            app.TornilloButton = uibutton(app.UIFigure, 'push');
            app.TornilloButton.ButtonPushedFcn = createCallbackFcn(app, @TornilloButtonPushed, true);
            app.TornilloButton.Text = 'Tornillo';
            app.TuercaButton = uibutton(app.UIFigure, 'push');
            app.TuercaButton.ButtonPushedFcn = createCallbackFcn(app, @TuercaButtonPushed, true);
            app.TuercaButton.Text = 'Tuerca';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp4 = uilamp(app.UIFigure);
            app.Lamp5 = uilamp(app.UIFigure);
            app.TCPLabel = uilabel(app.UIFigure);
            app.TCPLabel.Text = 'TCP';
            app.VALORESARTICULARESLabel = uilabel(app.UIFigure);
            app.VALORESARTICULARESLabel.Text = 'VALORES ARTICULARES  ';
            app.TORNILLOSLabel = uilabel(app.UIFigure);
            app.TORNILLOSLabel.Text = 'TORNILLOS ';
            app.TUERCASLabel = uilabel(app.UIFigure);
            app.TUERCASLabel.Text = 'TUERCAS';
            app.TCPLabel_2 = uilabel(app.UIFigure);
            app.TCPLabel_2.Text = 'TCP';
            app.VALORESARTICULARESLabel_2 = uilabel(app.UIFigure);
            app.VALORESARTICULARESLabel_2.Text = 'VALORES ARTICULARES  ';
            app.Label = uilabel(app.UIFigure);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.STOPButton_2 = uibutton(app.UIFigure, 'push');
            app.STOPButton_2.Text = 'STOP';
            app.Image = uiimage(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Label9';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Label10';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Label11';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'Label12';
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = 'Label13';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'Label14';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'Label15';
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'Label16';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end