classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalizadorderuidoV18UIFigure   matlab.ui.Figure
        UNIVERSIDADNACIONALDECOLOMBIASEDEBOGOTLabel  matlab.ui.control.Label
        LABORATORIODEENSAYOSELCTRICOSINDUSTRIALESLABELabel  matlab.ui.control.Label
        PROYECTORUIDOLabel             matlab.ui.control.Label
        imgLogoLabe                    matlab.ui.control.Image
        imgLogoU                       matlab.ui.control.Image
        btnLoadAudio                   matlab.ui.control.Button
        lblEstadoAudio                 matlab.ui.control.Label
        lblAudio                       matlab.ui.control.Label
        imgEstado                      matlab.ui.control.Image
        btnGraficar                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TiempoTab                      matlab.ui.container.Tab
        figTiempoChannelL              matlab.ui.control.UIAxes
        figTiempoChannelR              matlab.ui.control.UIAxes
        SliderTiempoX                  matlab.ui.control.Slider
        btnImpresoraTiempo             matlab.ui.control.Button
        CompararDropDown_2Label        matlab.ui.control.Label
        CompararDropDown_Tiempo        matlab.ui.control.DropDown
        FrecuenciaTab                  matlab.ui.container.Tab
        figFrecuenciaChannelR          matlab.ui.control.UIAxes
        SliderFrecuencia               matlab.ui.control.Slider
        TrazarArmonicosValor           matlab.ui.control.Spinner
        TrazarArmonicosSwitchLabel     matlab.ui.control.Label
        TrazarArmonicosSwitch          matlab.ui.control.Switch
        figFrecuenciaChannelL          matlab.ui.control.UIAxes
        btnImpresoraFrecuencia         matlab.ui.control.Button
        FLSpinnerLabel                 matlab.ui.control.Label
        FLSpinner                      matlab.ui.control.Spinner
        FHSpinnerLabel                 matlab.ui.control.Label
        FHSpinner                      matlab.ui.control.Spinner
        FiltroPasaBandaSwitchLabel     matlab.ui.control.Label
        FiltroPasaBandaSwitch          matlab.ui.control.Switch
        FiltroPSDpromedioSwitchLabel   matlab.ui.control.Label
        FiltroPSDpromedioSwitch        matlab.ui.control.Switch
        btnEstatusFiltroPB             matlab.ui.control.Button
        btnEstatusFiltroPSD            matlab.ui.control.Button
        CompararDropDownLabel          matlab.ui.control.Label
        CompararDropDown_Frecuencia    matlab.ui.control.DropDown
        TiempoFrecuenciaTab            matlab.ui.container.Tab
        figFrecuenciaChannelL_2        matlab.ui.control.UIAxes
        figTiempoChannelL_2            matlab.ui.control.UIAxes
        btnImpresoraTiempoyFrecuencia  matlab.ui.control.Button
        btnPlay                        matlab.ui.control.Button
        btnStop                        matlab.ui.control.Button
        btnLimpieza                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        signal;                     % Señal de audio.
        signal_back;                % Señal de respaldo del audio.
        Fs;                         % Frecuencia de muestreo de la señal.
        data_sounds;                % Data auxiliar para audios de acción.    
        tiempo;                     % Variable del vector tiempo de la señal.
        amplitud_maxima;            % Variable para amplitud máx en tiempo.
        periodo_muestreo;           % Variable para almacenar el periodo de muestreo de la señal.
        long_senal;                 % Variable para la lingitud vectorial de la señal.
        tipo_mono;                  % Flag para almacenar si la señal es mono o estereo.
        espectro_L;                 % Vector para el espectro de frecuencias en L.
        espectro_R;                 % Vector para el espectro de frecuencias en R.
        frecuencias_L;              % Vector de frecuencias para el canal L o señal mono.
        frecuencias_R;              % Vector de frecuencias para el canal R. 
        icon_exito;                 % Png con el icono de exito.        
        N_2;                        % Variable para almacenar el redondeo de la mitad de la longitud de la señal.
        data_standar;               % Data auxiliar para los audios estándar.
        
        
    end
    
    methods (Access = private)
        
        function Imprimir(app,ventana,path,name,extension)
            path_to_save = path;                                            % Path donde se guardarán las imagenes.
            [status,msg] = mkdir(path_to_save);                             % Si el path no existe lo crea, si existe no hace nada.
            waitbar(0.1,'Iniciando la captura de la imagen, por favor espere....'); % Informa al usuario del inicio de la captura de la imagen.
            %--------------------------------------------------------------------------------------------------------------------------
            fig = figure;                                                   % Crea una figura temporal.
            fig.Visible = 'off';                                            % La figura temporar no se muestra.
            figAxes = axes(fig);                                            % Le da ejes a la figura temporal.                     
            allChildren = ventana.XAxis.Parent.Children;                    % Copia todos los UIAxes children, Tomando los límites de ejes y el ratio de aspecto.
            waitbar(0.25,'Iniciando la captura de la imagen, por favor espere....'); % Informa al usuario del proceso de la captura de la imagen.
            copyobj(allChildren, figAxes)                                   % Copia el objeto como tal para extraer las demás propiedades.
            figAxes.XLim = ventana.XLim;                                    % Configura el límite en el eje X.
            figAxes.YLim = ventana.YLim;                                    % Configura el límite en el eje Y.            
            figAxes.XGrid = 'on';                                           % Activa la grilla en X.
            figAxes.YGrid = 'on';                                           % Activa la grilla en y.     
            waitbar(0.5,'Capturando la imagen, por favor espere....');      % Informa al usuario del proceso de la captura de la imagen.
            figAxes.GridLineStyle = ventana.GridLineStyle;                  % Copia el estilo de grilla.
            figAxes.XLabel.String = ventana.XLabel.String;                  % Copia el Label X.
            figAxes.YLabel.String = ventana.YLabel.String;                  % Copia el Label Y.
            %figAxes.PlotBoxAspectRatioMode = app.figTiempoChannelL.PlotBoxAspectRatioMode;
            %figAxes.PlotBoxAspectRatio = app.figTiempoChannelL.PlotBoxAspectRatio;
            %--------------------------------------------------------------------------------------------------------------------------
            max_images = 15;                                                % Cantidad máx de imagenes a guardar.
            aux_name = name;                                                % Nombre que se le da a las imagenes.   
            fileName = strings(1,max_images);                               % Vector de nombres de las imagenes a guardar.
            for i = 1:1:max_images
                fileName(i) = strcat(path_to_save,aux_name,num2str(i),extension);  % llenado del vector de nombres con los nombres y la extención.
            end 
            waitbar(0.75,'Guardando la imagen, por favor espere....');
            for i = 1:1:max_images
                if(exist(fileName(i)) == 2)                                 % Comprovamos que la imagen con el nombre en la posición del vector no exista.
                    if(i==max_images)                                       % Si se llega al tope de las imagenes avisa al usuario para que elimine las imagenes.
                        uiwait(msgbox('Almacenamiento máximo de imagenes alcanzado, por favor elimine imagenes para seguir almacenando.','Warning','warn'));
                        winopen(path);                              % Abre en el explorador el path --> Rervisar si funciona en MAC.
                    end
                    continue;
                else
                    waitbar(1,'Ultimando detalles....');                    
                    saveas(fig,fileName(i));                                % Si el nombre no existe lo guarda.
                    mensaje = strcat('Imagen guardada exitosamente!. Se guardo en: ',fileName(i));
                    uiwait(msgbox(mensaje,'Éxito','custom',app.icon_exito)); % Informa al usuario que la imagen se guardo exitosamente.
                    close all;
                    break;                                                  % Si la imagen se logró guardar se sale del ciclo que selecciona el nombre.
                end
            end
            delete(fig);                                                    % Borra la figura temporar    
            
        end
        
        function cargar_audio_texto(app)
            try                
                app.btnPlay.BackgroundColor = '#315EDB';    % Cambia el color de btn Play a Azul Claro
                msg = inputdlg({'Ingrese el nombre del archivo de audio:'},'Cargar Audio',1);   % Solicitud de ingreso de audio por teclado
                file = char(msg);                           % Conversión de input a char del dato ingresado
                tipo_archivo = '.wav';                      % Concatenación con el tipo de archivo ---> Cambiar para leer otros tipos de archivos
                file = strcat(file,tipo_archivo);           % Archivo a buscar en el path actual
                app.lblAudio.Text = file;                   % Muestra al usuario el audio cargado
                app.lblEstadoAudio.Text = 'Audio Cargado:'; % Avisa de la carga exitosa del audio
                [app.signal,app.Fs] = audioread(file);      % Convierte el audio en la señal signal y el frecuencia de muestreo Fs
                app.imgEstado.ImageSource = 'exito.png';    % Avisa al usuario de una conversión del audio exitosa
                sound(app.data_sounds.success,app.data_sounds.Fs_success); % Reproduce el sonido de exito
                %--------------------------------------------------------------------------------------------------------------------              
                app.tipo_mono = tipo_senal(app.signal);     % Función que verifica si el audio es mono o estereo
                                                            % retorna 1 si es mono 
                                                            % retorna 0 si es estereo 
                %--------------------------------------------------------------------------------------------------------------------
                %Abstracción de los parámetros de audio 
                app.periodo_muestreo = 1/app.Fs;            % Periodo de muestreo de la señal
                app.long_senal = size(app.signal,1);        % Longitud del vector de la señal
                app.tiempo = (0:app.long_senal-1)*app.periodo_muestreo; % Vector de tiempo de la señal
                                 
                    %Parámetros para graficar en frecuencia
                    if(app.tipo_mono)
                        %Canal L
                        X_mags = abs(fft(app.signal(:,1)));
                        bin_vals = [0 : app.long_senal-1];
                        fax_Hz = bin_vals*app.Fs/app.long_senal;
                        app.N_2 = ceil(app.long_senal/2);
                        app.frecuencias_L = fax_Hz(1:app.N_2);
                        app.espectro_L = 10*log10(X_mags(1:app.N_2));
                        
                    else
                        %Canal L
                        X_mags = abs(fft(app.signal(:,1)));
                        bin_vals = [0 : app.long_senal-1];
                        fax_Hz = bin_vals*app.Fs/app.long_senal;
                        app.N_2 = ceil(app.long_senal/2);
                        app.frecuencias_L = fax_Hz(1:app.N_2);
                        app.espectro_L = 10*log10(X_mags(1:app.N_2));
                        %Canal R
                        X_mags = abs(fft(app.signal(:,2)));
                        bin_vals = [0 : app.long_senal-1];
                        fax_Hz = bin_vals*app.Fs/app.long_senal;
                        app.N_2 = ceil(app.long_senal/2);
                        app.frecuencias_R = fax_Hz(1:app.N_2);
                        app.espectro_R = 10*log10(X_mags(1:app.N_2));
                                                
                    end                    
                %--------------------------------------------------------------------------------------
            catch 
                app.signal = 0;                             % Limpia la señal
                app.Fs = 0;                                 % Limpia la frecuencia de muestreo
                msgbox('Archivo de audio NO encontrado.','error','error');  % Avisa al usuario de que no se encuentra el audio solicitado
                app.lblEstadoAudio.Text = 'Estado:';        % Lbl de estado
                app.lblAudio.Text = 'Audio no encontrado';  % Avisa al usuario de que no se encontro el audio solicitado
                app.imgEstado.ImageSource = 'warning.png';  % Cambia a imgEstado con una imagen de warning.
                sound(app.data_sounds.warning,app.data_sounds.Fs_warning);  % Reproduce el sonido de waning
                return;
            end
            
        end
        
        function limpieza(app)
            clear sound;                                        % Detiene cualquier reproducción
            app.figTiempoChannelL.cla;                          % Limpia la figura de ploteo en el tiempo del canal L
            app.figTiempoChannelL_2.cla;                        % Limpia la figura de ploteo en el tiempo del canal L 2
            app.figTiempoChannelR.cla;                          % Limpia la figura de ploteo en el tiempo del canal R
            app.SliderTiempoX.Value = 5;                        % Retorna el Slider de Tiempo a 10
            app.SliderFrecuencia.Value = 5;                     % Retorna el Slider de Frecuencia a 10
            app.figFrecuenciaChannelL.cla;                      % Limpia la figura de ploteo en frecuencia del canal L
            app.figFrecuenciaChannelL_2.cla;                    % Limpia la figura de ploteo en frecuencia del canal L 2
            app.figFrecuenciaChannelR.cla;                      % Limpia la figura de ploteo en frecuencia del canal L   
            app.CompararDropDown_Tiempo.Value = 'Ninguna';      % Limpia el selector de la señal comparadora en tiempo
            app.CompararDropDown_Frecuencia.Value = 'Ninguna';  % Limpia el selector de la señal comparadora en frecuencia
            %----------------------------------
            % Limpieza de variables usadas
            app.signal = 0;                     
            app.Fs = 0;                         
            app.frecuencias_L = 0;
            app.frecuencias_R = 0;
            app.espectro_L = 0;
            app.espectro_R = 0;
            app.tiempo = 0;
            app.signal = 0;
            %----------------------------------
            app.imgEstado.ImageSource = 'en_espera2.png';               % Setea la configuración inicial.
            app.lblEstadoAudio.Text = 'Estado del Programa:';           % Setea la configuración inicial.
            app.lblAudio.Text = 'Introduzca un audio para comenzar';    % Setea la configuración inicial.
            app.TrazarArmonicosSwitch.Value = 'Off';                    % Apaga el Switch del Trazador de armonicos.
            app.FiltroPasaBandaSwitch.Value = 'Off';                    % Apaga el Switch del Filtro pasabandas.
            app.FiltroPSDpromedioSwitch.Value = 'Off';                  % Apaga el Switch del Filtro PSD.
            app.btnEstatusFiltroPB.Icon = 'filtro_init.png';            % Reinicia la imagen del icono del filtro pasabandas.
            app.btnEstatusFiltroPB.BackgroundColor = '#315EDB';         % Reinicia el color del icono del filtro pasabandas.
            app.btnEstatusFiltroPSD.Icon = 'rho.png';                   % Reinicia la imagen del icono del filtro PSD.
            app.btnEstatusFiltroPB.BackgroundColor = '#315EDB';         % Reinicia el color del icono del filtro PSD.            
        end
        
        function carga_audio_seleccion(app)
            
            try                
                app.btnPlay.BackgroundColor = '#315EDB';                                % Cambia el color de btn Play a Azul Claro.
                [file,path] = uigetfile('*.wav;*.mp3;*.m4a','Seleccione el audio a analizar: ');    % Abre el explorador y espera a que el usuario seleccione el audio.
                drawnow;                                                                % Dibuja la GUI para mantener la ventana al frente.
                figure(app.AnalizadorderuidoV18UIFigure)                                   % Dibuja la GUI para mantener la ventana al frente.
                if isequal(file,0)                                                      % Si el usuario no selecciona un audio entra.
                    app.limpieza();
                    msgbox('Ningun archivo de audio fue seleccionado.','Atención!','warn'); % Informa al usuario que no selecciono ningun archivo.
                else
                    cd(path);
                    app.lblAudio.Text = file;                   % Muestra al usuario el audio cargado
                    app.lblEstadoAudio.Text = 'Audio Cargado:'; % Avisa de la carga exitosa del audio
                    [app.signal,app.Fs] = audioread(file);      % Convierte el audio en la señal signal y el frecuencia de muestreo Fs
                    app.imgEstado.ImageSource = 'exito.png';    % Avisa al usuario de una conversión del audio exitosa
                    app.signal_back = app.signal;               % Almacena el audio de respaldo.
                    sound(app.data_sounds.success,app.data_sounds.Fs_success); % Reproduce el sonido de exito
                    app.procesamiento_audio(app.signal);        % Llamado a la función procesamiento de audio.              
                end
            catch 
                app.signal = 0;                             % Limpia la señal
                app.Fs = 0;                                 % Limpia la frecuencia de muestreo
                msgbox('Archivo de audio NO encontrado.','error','error');  % Avisa al usuario de que no se encuentra el audio solicitado
                app.lblEstadoAudio.Text = 'Estado:';        % Lbl de estado
                app.lblAudio.Text = 'Audio no encontrado';  % Avisa al usuario de que no se encontro el audio solicitado
                app.imgEstado.ImageSource = 'warning.png';  % Cambia a imgEstado con una imagen de warning.
                sound(app.data_sounds.warning,app.data_sounds.Fs_warning);  % Reproduce el sonido de waning
                return;
            end
        end
        
        function graficar(app)
                               
            try
                %--------------------------------------------------------------------------
                %Grafica en tiempo para la señal mono
                if(app.tipo_mono)
                    plot(app.figTiempoChannelL,app.tiempo,app.signal(:,1));             % Gráfica la señal en el tiempo en la fig tiempo L.
                    app.figTiempoChannelL.XLim = [0 1];                                 % Límita la gráfica al primer segundo.
                %---------------------------------------------------------------------------
                
                
                %---------------------------------------------------------------------------
                %Grafica en frecuenca para señal mono
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);   % Gráfica la señal en frecuencia en la fig frecuencia L. 
                    app.figFrecuenciaChannelL.XLim = [0 700];                           % Límita la gráfica a 700 Hz.
                %--------------------------------------------------------------------------
                
                
                %--------------------------------------------------------------------------
                %Grafica para el canal Dual
                    plot(app.figTiempoChannelL_2,app.tiempo,app.signal(:,1));           % Gráfica la señan en el tiempo en la fig tiempo L 2.
                    app.figTiempoChannelL_2.XLim = [0 1];                               % Límita la gráfica en el tiempo al primer segundo.
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L)  % Gráfica la señal en frecuencia en la fig frecuencia L 2.
                    app.figFrecuenciaChannelL_2.XLim = [0 700];                         % Límita la gráfica a 700 Hz.
                %--------------------------------------------------------------------------
                    
                else                    
                %--------------------------------------------------------------------------
                %Graficando en Tiempo para una señal estereo
                %Canal L
                    plot(app.figTiempoChannelL,app.tiempo,app.signal(:,1));             % Gráfica la señal en el tiempo en fig tiempo L.  
                    app.figTiempoChannelL.XLim = [0 1];                                 % Límita la gráfica al primer segundo.
                %Canal R
                    plot(app.figTiempoChannelR,app.tiempo,app.signal(:,2));             % Gráfica la señal en el tiempo en fig tiempo R.
                    app.figTiempoChannelR.XLim = [0 1];                                 % Límita la gráfica al primer segundo.
                %---------------------------------------------------------------------------
                    
                %--------------------------------------------------------------------------
                %Graficando en fourier
                %Canal L
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);   % Gráfica la señal en frecuencias en fig frecuencia L.
                    app.figFrecuenciaChannelL.XLim = [0 700];                           % Límita la gráfica a 700 Hz.
                    
                %CanalR
                    plot(app.figFrecuenciaChannelR,app.frecuencias_R,app.espectro_R);   % Gráfica la señal en frecuencia en fig frecuencia R.
                    app.figFrecuenciaChannelR.XLim = [0 700];                           % Límita la gráfica a 700 Hz.
                    
                    
                %--------------------------------------------------------------------------
                %Grafica para el canal Dual
                    plot(app.figTiempoChannelL_2,app.tiempo,app.signal(:,1));           % Gráfica la señal en el tiempo en fig tiempo L 2.
                    app.figTiempoChannelL_2.XLim = [0 1];                               % Límita la gráfica al primer segundo.
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L)  % Gráfica la señal en frecuencia en fig frecuencia L 2.
                    app.figFrecuenciaChannelL_2.XLim = [0 700];                         % Límita la gráfica a 700 Hz.
                %--------------------------------------------------------------------------
                end          
            catch 
                msgbox('Ingrese un archivo de audio para poder Gráficarlo');  % Mensaje de salida para informar al usuario de que no se pudo graficar la señal.
                app.imgEstado.ImageSource = 'warning.png';                    % Img Estatus en warning.
                app.lblAudio = 'Ingrese un archivo de audio para Gráficar';   % Informe al usuario de que no se pudo graficar.
            end            
        end
        
        function procesamiento_audio(app,signal)
            %--------------------------------------------------------------------------------------------------------------------              
                    app.tipo_mono = tipo_senal(signal);     % Función que verifica si el audio es mono o estereo
                                                                % retorna 1 si es mono 
                                                                % retorna 0 si es estereo 
                    %--------------------------------------------------------------------------------------------------------------------
                    %Abstracción de los parámetros de audio 
                    app.periodo_muestreo = 1/app.Fs;            % Periodo de muestreo de la señal
                    app.long_senal = size(signal,1);        % Longitud del vector de la señal
                    app.tiempo = (0:app.long_senal-1)*app.periodo_muestreo; % Vector de tiempo de la señal
                                     
                        %Parámetros para graficar en frecuencia
                        if(app.tipo_mono)
                            %Canal L
                            X_mags = abs(fft(signal(:,1)));
                            bin_vals = [0 : app.long_senal-1];
                            fax_Hz = bin_vals*app.Fs/app.long_senal;
                            app.N_2 = ceil(app.long_senal/2);
                            app.frecuencias_L = fax_Hz(1:app.N_2);
                            app.espectro_L = 10*log10(X_mags(1:app.N_2));
                            
                        else
                            %Canal L
                            X_mags = abs(fft(signal(:,1)));
                            bin_vals = [0 : app.long_senal-1];
                            fax_Hz = bin_vals*app.Fs/app.long_senal;
                            app.N_2 = ceil(app.long_senal/2);
                            app.frecuencias_L = fax_Hz(1:app.N_2);
                            app.espectro_L = 10*log10(X_mags(1:app.N_2));
                            %Canal R
                            X_mags = abs(fft(signal(:,2)));
                            bin_vals = [0 : app.long_senal-1];
                            fax_Hz = bin_vals*app.Fs/app.long_senal;
                            app.N_2 = ceil(app.long_senal/2);
                            app.frecuencias_R = fax_Hz(1:app.N_2);
                            app.espectro_R = 10*log10(X_mags(1:app.N_2));
                        end                    
                    %--------------------------------------------------------------------------------------  
        end
        
        function detener_reproduccion(app)
            try
                clear sound;                                % Para la reproducción del audio.
                app.imgEstado.ImageSource = 'mute.png';     % Img estatus en mute.                             
                app.btnPlay.BackgroundColor = '#315EDB';    % Color de fondo de btn Escuchar a azul claro.
                app.btnPlay.Icon = 'play.png';              % Cambia el btn de play al icono de play.
            catch 
                app.imgEstado.ImageSource = 'warning.png';  % Img warning si sucede algun error al detener el sonido.
                msgbox('cargue un archivo de audio')        % Informa al usuario que carge un archivo de audio.
            end            
        end
        
        function funtion_trazdor_armonicos(app,swt_status,value)
            flag = strcmp(swt_status,'On');                             % Bandera para conocer el estado del trazador [On/Off].
            num_arm = 30;                                               % Número de armónicos a trazar.
            if(flag)                                                    % Si se enciende el trazador entra.
                if(app.tipo_mono)
                    %Traza los armónicos para una señal mono.
                    for i = 1:1:num_arm                                 % Vector de enteros con la secuencia de armónicos.
                        xline(app.figFrecuenciaChannelL,i*value,'r--'); % Dibuja una línea Rojo vertical en el armónico correspondiente en el canal L.
                    end
                else
                    %Traza los armónicos para una señal estereo.
                    for i = 1:1:num_arm                                 % Vector de enteros con la secuencia de armónicos.
                        xline(app.figFrecuenciaChannelL,i*value,'r--'); % Dibuja una línea Rojo vertical en el armónico correspondiente en el canal L.
                        xline(app.figFrecuenciaChannelR,i*value,'r--'); % Dibuja una línea Rojo vertical en el armónico correspondiente en el canal R.
                    end
                end
                
            elseif(flag == 0)                                           % Si el trazador se apaga entra.
                if(app.tipo_mono)
                    %Limpia el trazador de armónicos para una señal mono.
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);
                    app.figFrecuenciaChannelL.XLim = [0 700];
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L);
                    app.figFrecuenciaChannelL_2.XLim = [0 700];
                else
                    %Limpia el trazador de armónicos para una señal estereo.
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);
                    app.figFrecuenciaChannelL.XLim = [0 700];
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L);
                    app.figFrecuenciaChannelL_2.XLim = [0 700];
                    plot(app.figFrecuenciaChannelR,app.frecuencias_R,app.espectro_R);
                    app.figFrecuenciaChannelR.XLim = [0 700];
                end                
            end
        end
        
        function funtion_filtro_pb(app,swt_status)
            flag_encendido = strcmp(swt_status,'On');                       % Bandera para saber el estado del filtro.
            %-------------------------------------------------------------------------------------------------------------------------------------
            % Cuando el Switch se ponga en ON
            if(flag_encendido)
                app.FiltroPSDpromedioSwitch.Value = 'Off';                  % Apaga el Switch del Filtro PSD.
                %---------------------------------------------------------------------------------------------------------------------------------
                try
                % Para una señal Mono
                if(app.tipo_mono)                                           % Si la señal es tipo mono se ejecuta
                    FL = app.FLSpinner.Value;                               % Se captura el valor de baja frecuencia para el filtro pasa banda                              
                    FH = app.FHSpinner.Value;                               % Se captura el valor de alta frecuencia para el filtro pasa banda
                    f = waitbar(0.1,'Por favor espere...');                 % Ventana de espera que le informa al usuario del estado del programa                                      
                    filtrado = bandpass(app.signal(:,1),[FL FH],app.Fs);    % Se filtra la señal de entrada en función de FL y FH
                    app.signal = filtrado;                                  % Almacena la señal filtrada en signal.
                    app.graficar();                                         % Grafíca la señal filtrada tanto en tiempo como en frecuencia.
                    X_mags = abs(fft(filtrado));                            % Se saca el valor absoluto de la transformada de Fourier de la señal filtrada
                    waitbar(0.5,'Trabajando...');                           % Ventana de espera que le informa al usuario del estado del programa 
                    app.espectro_L = 10*log10(X_mags(1:app.N_2));           % Se saca la señal filtrada en dB
                    waitbar(0.99,'Listo!');                                 % Ventana de espera que le informa al usuario del estado del programa 
                    app.signal(:,1) = ifft(app.espectro_L,app.long_senal,'symmetric');     % Convierte la señal original en el canal L  a la señal filtrada en el canal L
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);      % Gráfica en el Canal L de frecuenca la señal filtrada
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L);    % Gráfica en el Canal L_2 de frecuencia la señal filtrada
                    app.btnEstatusFiltroPB.Icon = 'alerta.png';             % Cambia el icono del filtro para informar de la utilización del filtro
                    app.btnEstatusFiltroPB.BackgroundColor = '#002FB3';     % Cambia el color de fondo del icono del filtro Pasa bandas
                    close all;                                              % Cierra todas las ventanas que informe al usuario
                %---------------------------------------------------------------------------------------------------------------------------------
                % Para una señal Estereo
                else                                                        % Si la señal es estereo se ejecuta
                    f = waitbar(0.1,'Por favor espere...');                 % Ventana de espera que le informa al usuario del estado del programa 
                    FL = app.FLSpinner.Value;                               % Se captura el valor de baja frecuencia para el filtro pasa banda  
                    FH = app.FHSpinner.Value;                               % Se captura el valor de alta frecuencia para el filtro pasa banda  
                    f = waitbar(0.15,'Trabajando en el canal L...');        % Ventana de espera que le informa al usuario del estado del programa                                   
                    filtrado_L = bandpass(app.signal(:,1),[FL FH],app.Fs);  % Se filtra la señal de entrada en el canal L en función de FL y FH
                    waitbar(0.3,'Trabajando en el canal R...');             % Ventana de espera que le informa al usuario del estado del programa 
                    filtrado_R = bandpass(app.signal(:,2),[FL FH],app.Fs);  % Se filtra la señal de entrada en el canal R en función de FL y FH
                    app.signal(:,1) = filtrado_L;                           % Almacena la señal filtrada en signal canal L.
                    app.signal(:,2) = filtrado_R;                           % Almacena la señal filtrada en signal canal R.
                    app.graficar();                                         % Grafíca la señal filtrada tanto en tiempo como en frecuencia.
                    waitbar(0.45,'Calculando la TF para el canal L...');    % Ventana de espera que le informa al usuario del estado del programa 
                    X_mags_L = abs(fft(filtrado_L));                        % Se saca el valor absoluto de la transformada de Fourier de la señal filtrada en el canal L
                    waitbar(0.6,'Calculando la TF para el canal R...');     % Ventana de espera que le informa al usuario del estado del programa 
                    X_mags_R = abs(fft(filtrado_R));                        % Se saca el valor absoluto de la transformada de Fourier de la señal filtrada en el canal R
                    waitbar(0.75,'Gráficando para el canal L...');          % Ventana de espera que le informa al usuario del estado del programa 
                    app.espectro_L = 10*log10(X_mags_L(1:app.N_2));         % Se saca la señal filtrada en dB para el canal L
                    waitbar(0.9,'Gráficando para el canal R...');           % Ventana de espera que le informa al usuario del estado del programa 
                    app.espectro_R = 10*log10(X_mags_R(1:app.N_2));         % Se saca la señal filtrada en dB para el canal R
                    waitbar(0.99,'Listo!');                                 % Ventana de espera que le informa al usuario del estado del programa              
                    app.signal(:,1) = ifft(app.espectro_L,app.long_senal,'symmetric');     % Convierte la señal original en el canal L  a la señal filtrada en el canal L
                    app.signal(:,2) = ifft(app.espectro_R,app.long_senal,'symmetric');     % Convierte la señal original en el canal R  a la señal filtrada en el canal R
                    plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);   % Gráfica en el Canal L de frecuenca la señal filtrada
                    plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L); % Gráfica en el Canal L_2 de frecuenca la señal filtrada
                    plot(app.figFrecuenciaChannelR,app.frecuencias_R,app.espectro_R);   % Gráfica en el Canal R de frecuenca la señal filtrada
                    app.btnEstatusFiltroPB.Icon = 'alerta.png';             % Cambia el icono del filtro para informar de la utilización del filtro
                    app.btnEstatusFiltroPB.BackgroundColor = '#002FB3';     % Cambia el color de fondo del icono del filtro Pasa bandas
                    close all;                                              % Cierra todas las ventanas que informe al usuario
                end  
                    
                catch
                    close all;
                    app.FiltroPasaBandaSwitch.Value = 'Off';
                    wait(msgbox('Carge un archivo de audio para poder filtrarlo!!!','ERROR','error'));                    
                end
            %------------------------------------------------------------------------------------------ 
            % Cuando el Switch se ponga en OFF
            else
                app.btnEstatusFiltroPB.Icon = 'filtro_init.png';    % Cambia el icono del filtro para informar de la utilización del filtro.
                app.btnEstatusFiltroPB.BackgroundColor = '#315EDB'; % Cambia el color de fondo del icono del filtro Pasa bandas.
                app.signal = app.signal_back;                       % Retorna la señal a su estado antes del filtrado.
                app.procesamiento_audio(app.signal);                % Procesa la señal nuevamente.
                app.graficar();                                     % Grafica la señal sin filtrado.
                app.FLSpinner.Value = 7;                            % Valor de FL al apagar el filtro.
                app.FHSpinner.Value = 650;                          % Valor de FH al apagar el filtro.
                app.TrazarArmonicosSwitch.Value = 'Off';            % Apaga el Switch del Trazador de armonicos.
                app.FiltroPSDpromedioSwitch.Value = 'Off';          % Apaga el Switch del Filtro PSD.
            end
            
        end
        
        function funtion_filtro_PSD(app,swt_status)
            flag_encendido = strcmp(swt_status,'On');                      % Flag de estado del swicth.
            %-------------------------------------------------------------------------------------------------------------------------------------
            % Cuando el Switch se ponga en ON
            if(flag_encendido)
                %---------------------------------------------------------------------------------------------------------------------------------
                % Para una señal Mono
                if(app.tipo_mono)                                          % Si la señal es tipo mono se ejecuta.                    
                    filtro_PSD(app,'L');                                   % Llamado a la función filtro_PSD que ejecuta el filtrado.                    
                    app.btnEstatusFiltroPSD.Icon = 'alerta.png';           % Cambio en el icono de status del filtro PSD a alerta.
                    app.btnEstatusFiltroPSD.BackgroundColor = '#002FB3';   % Cambio en el color del btn del status del filtro PSD.
                %---------------------------------------------------------------------------------------------------------------------------------
                % Para una señal Estereo
                else                                                       % Si la señal es estereo se ejecuta.
                    filtro_PSD(app,'R')                                    % Se llama a la función filtro PSD para el canal R.
                    app.btnEstatusFiltroPSD.Icon = 'alerta.png';           % se cambia la imagen de estatos del filtro a alerta.
                    app.btnEstatusFiltroPSD.BackgroundColor = '#002FB3';   % se cambia el color de fondo del btn status del filtro.
                end
            %------------------------------------------------------------------------------------------ 
            % Cuando el Switch se ponga en OFF
            else  
                app.TrazarArmonicosSwitch.Value = 'Off';                % Apaga el Switch del Trazador de armonicos.
                app.FiltroPasaBandaSwitch.Value = 'Off';                % Apaga el Switch del Filtro pasabandas.
                app.btnEstatusFiltroPSD.Icon = 'rho.png';               % Informa al usuario del estado off del filtro.
                app.btnEstatusFiltroPSD.BackgroundColor = '#315EDB';    % Cambio el fondo del btn status del filtro PSD.
                app.signal = app.signal_back;                           % Retorna la señal a su estado antes del filtrado.
                app.procesamiento_audio(app.signal);                    % Procesa la señal nuevamente.
                app.graficar();                                         % Grafica la señal sin filtrado.
            end
            
        end
        
        function comparacion_funtion(app,value)
            corona = "Corona";                              % Variable para comparar corona
            tracking = "Tracking";                          % Variable para comparar tracking
            bag_tracking = "Bad tracking";                  % Variable para comparar bad tracking
            tracking_insulation = "Tracking Insulation";    % Variable para comparar tracking insulation
            arco = "Arco";                                  % Variable para comparar arco
            app.CompararDropDown_Tiempo.Value = value;      % Sincranización de los drops down
            app.CompararDropDown_Frecuencia.Value = value;  % Sincranización de los drops down
            
            if(strcmp(value,corona))                    % Si se selecciona corona entra
                plot(app.figTiempoChannelR,app.data_standar.tiempo_corona,app.data_standar.signal_corona);
                app.figTiempoChannelR.XLim = [0 1];
                plot(app.figFrecuenciaChannelR,app.data_standar.frecuencias_corona,app.data_standar.espectro_corona);
                app.figFrecuenciaChannelR.XLim = [0 700];
                app.figTiempoChannelR.Title.String = "Señal estándar Corona";
                app.figFrecuenciaChannelR.Title.String = "Señal estándar Corona";
                
                
            elseif(strcmp(value,tracking))              % Si se selecciona tracking entra
                plot(app.figTiempoChannelR,app.data_standar.tiempo_tracking,app.data_standar.signal_tracking);
                app.figTiempoChannelR.XLim = [0 1];
                plot(app.figFrecuenciaChannelR,app.data_standar.frecuencias_tracking,app.data_standar.espectro_tracking);
                app.figFrecuenciaChannelR.XLim = [0 700];
                app.figTiempoChannelR.Title.String = "Señal estándar Tracking";
                app.figFrecuenciaChannelR.Title.String = "Señal estándar Tracking";
                
                
            elseif(strcmp(value,arco))                  % Si se selecciona arco entra
                plot(app.figTiempoChannelR,app.data_standar.tiempo_arco,app.data_standar.signal_arco);
                app.figTiempoChannelR.XLim = [0 1];
                plot(app.figFrecuenciaChannelR,app.data_standar.frecuencias_arco,app.data_standar.espectro_arco);
                app.figFrecuenciaChannelR.XLim = [0 700];
                app.figTiempoChannelR.Title.String = "Señal estándar Arco";
                app.figFrecuenciaChannelR.Title.String = "Señal estándar Arco";
                
            elseif(strcmp(value,bag_tracking))          % Si se selecciona bag tracking entra
                plot(app.figTiempoChannelR,app.data_standar.tiempo_bad_tracking,app.data_standar.signal_bad_tracking);
                app.figTiempoChannelR.XLim = [0 1];
                plot(app.figFrecuenciaChannelR,app.data_standar.frecuencias_bad_tracking,app.data_standar.espectro_bad_tracking);
                app.figFrecuenciaChannelR.XLim = [0 700];
                app.figTiempoChannelR.Title.String = "Señal estándar Bad tracking";
                app.figFrecuenciaChannelR.Title.String = "Señal estándar Bad tracking";
                
            elseif(strcmp(value,tracking_insulation))   % Si se selecciona tracking insulation entra
                plot(app.figTiempoChannelR,app.data_standar.tiempo_tracking_insulation,app.data_standar.signal_tracking_insulation);
                app.figTiempoChannelR.XLim = [0 1];
                plot(app.figFrecuenciaChannelR,app.data_standar.frecuencias_tracking_insulation,app.data_standar.espectro_tracking_insulation);
                app.figFrecuenciaChannelR.XLim = [0 700];
                app.figTiempoChannelR.Title.String = "Señal estándar Tracking Insulation";
                app.figFrecuenciaChannelR.Title.String = "Señal estándar Tracking Insulation";
                
            else                                        % Si se selecciona ninguna o ocurre un error en la selección
                app.figTiempoChannelR.cla;
                app.figTiempoChannelR.Title.String = "Canal Derecho";
                app.figFrecuenciaChannelR.cla;
                app.figFrecuenciaChannelR.Title.String = "Canal Derecho";
                
                
            end
        end
    end
    
    methods (Access = private)
        
        function filtro_PSD(app,canal)                                              % Función del Filtro PSD recibe como parámetro de entrada el canal que se desea filtrar.
            flag_canal = strcmp('L',canal);                                         % Flag para verificar a que canal se le desea aplicar el filtro.                                 
            if(flag_canal)
                %Aplicación del filtro al canal L
                waitbar(0.3,'Iniciando el Filtrado, por favor espere...')           % Se le informa al usuario del inicio del filtrado.
                [pxx,fs] = pwelch(app.signal(:,1),[],[],[],app.Fs);                 % Se filtra la señal del canal L.
                aux = 10*log10(pxx);                                                % Se saca los dB de la señal filtrada.
                minimo = min(aux(1:100,:));                                         % Se muestrea el mínimo pico de los 100 primeros valores de la señal muestreada.
                p = aux + abs(minimo);                                              % Se hace un offset de la señal filtrada en función del mínimo valor de los 100 primeros valores.
                waitbar(0.6,'Terminando de filtrar, por favor espere...')           % Se le informa al usuario del progreso del filtrado.
                app.frecuencias_L = fs;                                             % Se le asigna las frecuencias filtradas a la variable frecuencias_L.
                app.espectro_L = p;                                                 % Se le asigna los valores de la señal filtrada a la variable espectro_L.
                waitbar(0.9,'Graficando....')                                       % Se le informa al usuario del progreso del filtrado.
                plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);   % Se plotea la señal filtrada en Frecuencias canal L.
                app.figFrecuenciaChannelL.XLim = [0 700];                           % Se límita el eje X de Frecuencias canal L.
                waitbar(1,'Completado...')                                          % Se le informa al usuario del progreso del filtrado.
                plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L); % Se plotea la señal filtrada en Frecuencias canal L 2.
                app.figFrecuenciaChannelL_2.XLim = [0 700];                         % Se límita el eje X de Frecuencias canal L 2.
                close all;                                                          % Se cierran todas las ventanas de información al usuario.
                %app.signal = ifft(p,app.long_senal,'symmetric')
                %sound(app.signal,app.Fs)    PARA FUTUROS DESARROLLOS.
            else
                %Aplicación del filtro para los dos canales
                %Aplicación del filtro al canal L
                waitbar(0.3,'Iniciando el filtrado, por favor espere...')           % Se le informa al usuario del inicio del filtrado.
                [pxx,fs] = pwelch(app.signal(:,1),[],[],[],app.Fs);                 % Se filtra la señal del canal L.
                aux = 10*log10(pxx);                                                % Se saca los dB de la señal filtrada.
                minimo = min(aux(1:100,:));                                         % Se muestrea el mínimo pico de los 100 primeros valores de la señal muestreada.
                p = aux + abs(minimo);                                              % Se hace un offset de la señal filtrada en función del mínimo valor de los 100 primeros valores.
                app.frecuencias_L = fs;                                             % Se le asigna las frecuencias filtradas a la variable frecuencias_L.
                app.espectro_L = p;                                                 % Se le asigna los valores de la señal filtrada a la variable espectro_L.
                plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);   % Se plotea la señal filtrada en Frecuencias canal L. 
                app.figFrecuenciaChannelL.XLim = [0 700];                           % Se límita el eje X de Frecuencias canal L.
                waitbar(0.6,'Filtrando, por favor espere...')                       % Se le informa al usuario del progreso del filtrado.
                plot(app.figFrecuenciaChannelL_2,app.frecuencias_L,app.espectro_L); % Se plotea la señal filtrada en Frecuencias canal L 2. 
                app.figFrecuenciaChannelL_2.XLim = [0 700];                         % Se límita el eje X de Frecuencias canal L 2.
                %Aplicación del filtro al canal R
                [pxx,fs] = pwelch(app.signal(:,2),[],[],[],app.Fs);                 % Se filtra la señal del canal R.
                aux = 10*log10(pxx);                                                % Se saca los dB de la señal filtrada.
                minimo = min(aux(1:100,:));                                         % Se muestrea el mínimo pico de los 100 primeros valores de la señal muestreada.
                p = aux + abs(minimo);                                              % Se muestrea el mínimo pico de los 100 primeros valores de la señal muestreada.
                waitbar(0.9,'Terminando de filtrar, por favor espere...')           % Se le informa al usuario del progreso del filtrado.
                app.frecuencias_R = fs;                                             % Se le asigna las frecuencias filtradas a la variable frecuencias_R.
                app.espectro_R = p;                                                 % Se le asigna los valores de la señal filtrada a la variable espectro_R.
                waitbar(1,'Completado')                                             % Se le informa al usuario del progreso del filtrado.
                plot(app.figFrecuenciaChannelR,app.frecuencias_R,app.espectro_R);   % Se plotea la señal filtrada en Frecuencias canal R. 
                app.figFrecuenciaChannelR.XLim = [0 700];                           % Se límita el eje X de Frecuencias canal R.
                close all;                                                          % Se cierran todas las ventanas de información al usuario.
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %------------------------------------------------------------------------------------------------------
            % Función para ejecutar al arranque de la GUI
            drawnow;                                                % Dibuja la GUI
            %app.AnalizadorderuidoV18UIFigure.WindowState = 'maximized';% Máximiza la GUI al arranque            
            app.imgEstado.ImageSource = 'en_espera2.png';           % Setea la configuración inicial
            app.lblEstadoAudio.Text = 'Estado del Programa:';       % Setea la configuración inicial
            app.lblAudio.Text = 'Introduzca un audio para comenzar';% Setea la configuración inicial
            app.data_sounds = load('sounds.mat');                   % Carga los archivos de audio Auxiliar
            app.data_standar = load('stdr_signal.mat');             % Carga los archivos de audio auxiliares en tiempo
            app.icon_exito = imread('exito.png');                   % Carga el icono Exito con la imagen Exito
            app.CompararDropDown_Tiempo.Value = 'Ninguna';      % Limpia el selector de la señal comparadora en tiempo
            app.CompararDropDown_Frecuencia.Value = 'Ninguna';  % Limpia el selector de la señal comparadora en frecuencia
            
            %------------------------------------------------------------------------------------------------------
        end

        % Button pushed function: btnLoadAudio
        function btnLoadAudioButtonPushed(app, event)
            
            app.limpieza();             % Llama a la función limpieza.
            app.carga_audio_seleccion;  % Llama a la función para cargar el audio por selección de archivos.
            
        end

        % Button pushed function: btnGraficar
        function btnGraficarButtonPushed(app, event)
            app.graficar();
        end

        % Button pushed function: btnPlay
        function btnPlayButtonPushed(app, event)
                try
                    clear sound;                                % Para la reproducción del audio.
                    sound(app.signal,app.Fs);                   % Reproduce el audio.
                    app.imgEstado.ImageSource = 'sonido.png';   % Img Estatos en sonando.
                    app.btnPlay.BackgroundColor = '#002FB3';    % Cambia el color de fondo de btn Escuchar.
                    app.btnPlay.Icon = 'reproduciendo.png';     % Cambia el btn Play con el icono de reproduciendo.
                catch 
                    msgbox('Carge un archivo de audio');        % Informa al usuario de cargar un archivo.
                    app.imgEstado.ImageSource = 'warning.png';  % Img estatus en Warning.
                    sound(app.data_sounds.warning,app.data_sounds.Fs_warning); % Reproduce el audio de warning.
                end 
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
                app.detener_reproduccion();
        end

        % Value changed function: SliderTiempoX
        function SliderTiempoXValueChanged(app, event)
            value = app.SliderTiempoX.Value;    % Captura el valor del slider.
            value = value/10;                   % Factor de Escala para las gráficas en tiempo.
            %----------------------------------------------------------------------------------------
            %Graficas en función del tiempo
            if(app.tipo_mono)
                %------------------------------------------------------------------------------------
                %Gráfica Mono
                plot(app.figTiempoChannelL,app.tiempo,app.signal(:,1));  
                app.figTiempoChannelL.XLim = [value-0.5 value+0.5];
                %------------------------------------------------------------------------------------
            else
                %Gráfica Estereo
                %------------------------------------------------------------------------------------
                %canal L
                plot(app.figTiempoChannelL,app.tiempo,app.signal(:,1));  
                app.figTiempoChannelL.XLim = [value-0.5 value+0.5];
                %------------------------------------------------------------------------------------
                
                %------------------------------------------------------------------------------------
                %Canal R
                plot(app.figTiempoChannelR,app.tiempo,app.signal(:,2));  
                app.figTiempoChannelR.XLim = [value-0.5 value+0.5];
                %------------------------------------------------------------------------------------
            end
            %----------------------------------------------------------------------------------------
        end

        % Value changed function: SliderFrecuencia
        function SliderFrecuenciaValueChanged(app, event)
            app.TrazarArmonicosSwitch.Value = 'Off';        % Apaga el trazador de armónicos.
            value = app.SliderFrecuencia.Value;             % Captura el valor del slider.
            value = value*100;                              % Factor de escala para gráficas en frecuencia
            %--------------------------------------------------------------------------
            %Grafica en Frecuencia
            if(app.tipo_mono)
                %--------------------------------------------------------------------------
                %Gráfica señal Mono
                plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);
                app.figFrecuenciaChannelL.XLim = [value-500 value+500];
                %--------------------------------------------------------------------------                    
            else
                %--------------------------------------------------------------------------
                %Canal L
                plot(app.figFrecuenciaChannelL,app.frecuencias_L,app.espectro_L);
                app.figFrecuenciaChannelL.XLim = [value-500 value+500];
                %--------------------------------------------------------------------------
                
                %--------------------------------------------------------------------------
                %Canal R
                plot(app.figFrecuenciaChannelR,app.frecuencias_R,app.espectro_R);
                app.figFrecuenciaChannelR.XLim = [value-500 value+500];
                %--------------------------------------------------------------------------
            end
        end

        % Value changed function: TrazarArmonicosSwitch
        function TrazarArmonicosSwitchValueChanged(app, event)
            app.detener_reproduccion();                                 % Detiene la repodrucción del audio.
            swt_status = app.TrazarArmonicosSwitch.Value;               % Captura si se encendio el trazador de armónicos.
            value = app.TrazarArmonicosValor.Value;                     % Captura el valor del armónico a trazar. 
            app.funtion_trazdor_armonicos(swt_status,value)             % Llamada a la función trazador de armonicos.
        end

        % Button pushed function: btnImpresoraTiempo
        function btnImpresoraTiempoButtonPushed(app, event)
            extension = '.jpg';                                                     % Define la extención con la que se guardar la imagen.  
            path = 'Fig/tiempo/';                                                   % Define la ubicación en donde se guardará la imagen.
            if(app.tipo_mono)
                %Impresión para señal mono.
                app.Imprimir(app.figTiempoChannelL,path,'CanalL_tiempo',extension); % Llamado a la función impresión para el canal L.
            else
                app.Imprimir(app.figTiempoChannelL,path,'CanalL_tiempo',extension); % Llamado a la función impresión para el canal L.
                app.Imprimir(app.figTiempoChannelR,path,'CanalR_tiempo',extension); % Llamado a la función impresión para el canal R.
            end
            
                                                                                          
        end

        % Button pushed function: btnImpresoraFrecuencia
        function btnImpresoraFrecuenciaButtonPushed(app, event)
            extension = '.jpg';                                                             % Define la extención con la que se guardará la imagen.
            path = 'Fig/frecuencia/';                                                       % Define la ubicación donde se guardará la imagen.
            if(app.tipo_mono)
                %Impresión para señal mono.
                app.Imprimir(app.figFrecuenciaChannelL,path,'CanalL_frecuencia',extension); % Llamado a la función impresión para el canal L.
            else
                %Impresión para señal estereo. 
                app.Imprimir(app.figFrecuenciaChannelL,path,'CanalL_frecuencia',extension); % Llamado a la función impresión para el canal L.
                app.Imprimir(app.figFrecuenciaChannelR,path,'CanalR_frecuencia',extension); % Llamado a la función impresión para el canal R.
            end
        end

        % Button pushed function: btnImpresoraTiempoyFrecuencia
        function btnImpresoraTiempoyFrecuenciaButtonPushed(app, event)
            extension = '.jpg';                                                             % Define la extensión con la que se guardara la imagen.
            path = 'Fig/tiempo_y_frecuencia/';                                              % Define la ubicación en donde se guardará la imagen.
            app.Imprimir(app.figTiempoChannelL_2,path,'CanalL_tiempo',extension);           % Llamado a la función impresión para el canal del tiempo L 2.
            app.Imprimir(app.figFrecuenciaChannelL_2,path,'CanalR_frecuencia',extension);   % Llamado a la función impresión para el canal de la frecuencia L 2.
        end

        % Value changed function: FiltroPasaBandaSwitch
        function FiltroPasaBandaSwitchValueChanged(app, event)
            % Value = 'Off'
            % Value = 'On'
            app.detener_reproduccion();                                     % Detiene la repodrucción del audio.
            swt_status = app.FiltroPasaBandaSwitch.Value;                   % Captura el estado del filtro [On/Off].
            app.funtion_filtro_PSD('Off');                                  % Llamada a la función filtro PSD.
            app.funtion_filtro_pb(swt_status);                              % Llamado a la función filtro pasa bandas.
           
        end

        % Value changed function: FiltroPSDpromedioSwitch
        function FiltroPSDpromedioSwitchValueChanged(app, event)
            % Value = 'Off'
            % Value = 'On'
            app.detener_reproduccion();                                    % Detiene la repodrucción del audio.
            swt_status = app.FiltroPSDpromedioSwitch.Value;                % Captura del estado del swicth.
            app.funtion_filtro_pb('Off');                                  % Llamada a la función filtro pasa bandas.
            app.funtion_filtro_PSD(swt_status);                            % Llamada a la función filtro PSD.
            
        end

        % Button pushed function: btnLimpieza
        function btnLimpiezaButtonPushed(app, event)
            app.limpieza();
        end

        % Callback function
        function btnLimpiezaFrecuenciaButtonPushed(app, event)
            app.limpieza();
        end

        % Callback function
        function btnLimpiezaTiempoyFrecuenciaButtonPushed(app, event)
            app.limpieza();
        end

        % Callback function
        function CompararButtonGroupSelectionChanged(app, event)
            selectedButton = app.CompararButtonGroup.SelectedObject;
            disp(selectedButton);
        end

        % Value changed function: CompararDropDown_Frecuencia
        function CompararDropDown_FrecuenciaValueChanged(app, event)
            value = app.CompararDropDown_Frecuencia.Value;
            app.comparacion_funtion(value);
            
        end

        % Value changed function: CompararDropDown_Tiempo
        function CompararDropDown_TiempoValueChanged(app, event)
            value = app.CompararDropDown_Tiempo.Value;
            app.comparacion_funtion(value);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalizadorderuidoV18UIFigure = uifigure('Visible', 'off');
            app.AnalizadorderuidoV18UIFigure.Name = 'Analizador de ruido V1.8';
            app.UNIVERSIDADNACIONALDECOLOMBIASEDEBOGOTLabel = uilabel(app.AnalizadorderuidoV18UIFigure);
            app.UNIVERSIDADNACIONALDECOLOMBIASEDEBOGOTLabel.Text = 'UNIVERSIDAD NACIONAL DE COLOMBIA - SEDE BOGOTÁ';
            app.LABORATORIODEENSAYOSELCTRICOSINDUSTRIALESLABELabel = uilabel(app.AnalizadorderuidoV18UIFigure);
            app.LABORATORIODEENSAYOSELCTRICOSINDUSTRIALESLABELabel.Text = 'LABORATORIO DE ENSAYOS ELÉCTRICOS INDUSTRIALES - LABE';
            app.PROYECTORUIDOLabel = uilabel(app.AnalizadorderuidoV18UIFigure);
            app.PROYECTORUIDOLabel.Text = 'PROYECTO  RUIDO';
            app.imgLogoLabe = uiimage(app.AnalizadorderuidoV18UIFigure);
            app.imgLogoLabe.ImageSource = 'logoLABE.png';
            app.imgLogoU = uiimage(app.AnalizadorderuidoV18UIFigure);
            app.imgLogoU.ImageSource = 'logou.png';
            app.btnLoadAudio = uibutton(app.AnalizadorderuidoV18UIFigure, 'push');
            app.btnLoadAudio.ButtonPushedFcn = createCallbackFcn(app, @btnLoadAudioButtonPushed, true);
            app.btnLoadAudio.Icon = 'load.png';
            app.btnLoadAudio.Tooltip = {'Oprima este Botón para cargar un audio.'};
            app.btnLoadAudio.Text = '';
            app.lblEstadoAudio = uilabel(app.AnalizadorderuidoV18UIFigure);
            app.lblEstadoAudio.Text = '';
            app.lblAudio = uilabel(app.AnalizadorderuidoV18UIFigure);
            app.lblAudio.Text = '';
            app.imgEstado = uiimage(app.AnalizadorderuidoV18UIFigure);
            app.imgEstado.ImageSource = 'vacio.png';
            app.btnGraficar = uibutton(app.AnalizadorderuidoV18UIFigure, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarButtonPushed, true);
            app.btnGraficar.Icon = 'onda.png';
            app.btnGraficar.Tooltip = {'Oprima este botón para gráficar el audio cargado.'};
            app.btnGraficar.Text = '';
            app.TabGroup = uitabgroup(app.AnalizadorderuidoV18UIFigure);
            app.TiempoTab = uitab(app.TabGroup);
            app.TiempoTab.Title = 'Tiempo';
            app.figTiempoChannelL = uiaxes(app.TiempoTab);
            title(app.figTiempoChannelL, 'Canal Izquierdo')
            xlabel(app.figTiempoChannelL, 'Tiempo [s]')
            ylabel(app.figTiempoChannelL, 'Amplitud [%]')
            app.figTiempoChannelR = uiaxes(app.TiempoTab);
            title(app.figTiempoChannelR, 'Canal Derecho')
            xlabel(app.figTiempoChannelR, 'Tiempo [s]')
            ylabel(app.figTiempoChannelR, 'Amplitud [%]')
            app.SliderTiempoX = uislider(app.TiempoTab);
            app.SliderTiempoX.Limits = [5 90];
            app.SliderTiempoX.MajorTickLabels = {'', ''};
            app.SliderTiempoX.ValueChangedFcn = createCallbackFcn(app, @SliderTiempoXValueChanged, true);
            app.SliderTiempoX.Value = 5;
            app.btnImpresoraTiempo = uibutton(app.TiempoTab, 'push');
            app.btnImpresoraTiempo.ButtonPushedFcn = createCallbackFcn(app, @btnImpresoraTiempoButtonPushed, true);
            app.btnImpresoraTiempo.Icon = 'impresora.png';
            app.btnImpresoraTiempo.Tooltip = {'Oprima este botón para guardar  la gráfica del tiempo.'};
            app.btnImpresoraTiempo.Text = '';
            app.CompararDropDown_2Label = uilabel(app.TiempoTab);
            app.CompararDropDown_2Label.Text = 'Comparar';
            app.CompararDropDown_Tiempo = uidropdown(app.TiempoTab);
            app.CompararDropDown_Tiempo.Items = {'Ninguna', 'Corona', 'Tracking', 'Bad tracking', 'Tracking Insulation', 'Arco'};
            app.CompararDropDown_Tiempo.ValueChangedFcn = createCallbackFcn(app, @CompararDropDown_TiempoValueChanged, true);
            app.CompararDropDown_Tiempo.Tooltip = {'Seleccione la señal estandar con la que desea comparar.'};
            app.CompararDropDown_Tiempo.Value = 'Ninguna';
            app.FrecuenciaTab = uitab(app.TabGroup);
            app.FrecuenciaTab.Title = 'Frecuencia';
            app.figFrecuenciaChannelR = uiaxes(app.FrecuenciaTab);
            title(app.figFrecuenciaChannelR, 'Canal Derecho')
            xlabel(app.figFrecuenciaChannelR, 'Frecuencia [Hz]')
            ylabel(app.figFrecuenciaChannelR, 'Magnitud [dB]')
            app.SliderFrecuencia = uislider(app.FrecuenciaTab);
            app.SliderFrecuencia.Limits = [5 90];
            app.SliderFrecuencia.MajorTickLabels = {''};
            app.SliderFrecuencia.ValueChangedFcn = createCallbackFcn(app, @SliderFrecuenciaValueChanged, true);
            app.SliderFrecuencia.Value = 5;
            app.TrazarArmonicosValor = uispinner(app.FrecuenciaTab);
            app.TrazarArmonicosValor.Step = 0.5;
            app.TrazarArmonicosValor.Limits = [0 800];
            app.TrazarArmonicosValor.Value = 60;
            app.TrazarArmonicosSwitchLabel = uilabel(app.FrecuenciaTab);
            app.TrazarArmonicosSwitchLabel.Text = 'Trazar Armonicos';
            app.TrazarArmonicosSwitch = uiswitch(app.FrecuenciaTab, 'slider');
            app.TrazarArmonicosSwitch.ValueChangedFcn = createCallbackFcn(app, @TrazarArmonicosSwitchValueChanged, true);
            app.TrazarArmonicosSwitch.Tooltip = {'Enciende o apaga el Trazador de armonicos'};
            app.figFrecuenciaChannelL = uiaxes(app.FrecuenciaTab);
            title(app.figFrecuenciaChannelL, 'Canal Izquierdo')
            xlabel(app.figFrecuenciaChannelL, 'Frecuencia [Hz]')
            ylabel(app.figFrecuenciaChannelL, 'Magnitud [dB]')
            app.btnImpresoraFrecuencia = uibutton(app.FrecuenciaTab, 'push');
            app.btnImpresoraFrecuencia.ButtonPushedFcn = createCallbackFcn(app, @btnImpresoraFrecuenciaButtonPushed, true);
            app.btnImpresoraFrecuencia.Icon = 'impresora.png';
            app.btnImpresoraFrecuencia.Text = '';
            app.FLSpinnerLabel = uilabel(app.FrecuenciaTab);
            app.FLSpinnerLabel.Text = 'FL';
            app.FLSpinner = uispinner(app.FrecuenciaTab);
            app.FLSpinner.Limits = [7 Inf];
            app.FLSpinner.Tooltip = {'Valor de Frecuencia de corte baja para el filtro pasa bandas.'};
            app.FLSpinner.Value = 7;
            app.FHSpinnerLabel = uilabel(app.FrecuenciaTab);
            app.FHSpinnerLabel.Text = 'FH';
            app.FHSpinner = uispinner(app.FrecuenciaTab);
            app.FHSpinner.Limits = [11 Inf];
            app.FHSpinner.Tooltip = {'Valor de Frecuencia de corte alta para el filtro pasa bandas.'};
            app.FHSpinner.Value = 650;
            app.FiltroPasaBandaSwitchLabel = uilabel(app.FrecuenciaTab);
            app.FiltroPasaBandaSwitchLabel.Text = 'Filtro Pasa Banda';
            app.FiltroPasaBandaSwitch = uiswitch(app.FrecuenciaTab, 'slider');
            app.FiltroPasaBandaSwitch.ValueChangedFcn = createCallbackFcn(app, @FiltroPasaBandaSwitchValueChanged, true);
            app.FiltroPasaBandaSwitch.Tooltip = {'Enciende o Apaga el filtro pasabandas con los valores de FL y FH seleccionados'};
            app.FiltroPSDpromedioSwitchLabel = uilabel(app.FrecuenciaTab);
            app.FiltroPSDpromedioSwitchLabel.Text = 'Filtro PSD promedio';
            app.FiltroPSDpromedioSwitch = uiswitch(app.FrecuenciaTab, 'slider');
            app.FiltroPSDpromedioSwitch.ValueChangedFcn = createCallbackFcn(app, @FiltroPSDpromedioSwitchValueChanged, true);
            app.FiltroPSDpromedioSwitch.Tooltip = {'Enciende o Apaga el filtro PSD.'};
            app.btnEstatusFiltroPB = uibutton(app.FrecuenciaTab, 'push');
            app.btnEstatusFiltroPB.Icon = 'filtro_init.png';
            app.btnEstatusFiltroPB.Tooltip = {'Indica si el Filtro Pasabandas se encuentra encendido o no.'};
            app.btnEstatusFiltroPB.Text = '';
            app.btnEstatusFiltroPSD = uibutton(app.FrecuenciaTab, 'push');
            app.btnEstatusFiltroPSD.Icon = 'rho.png';
            app.btnEstatusFiltroPSD.Tooltip = {'Indica si el Filtro de densidad espectral de potencia se encuentra encendido o no.'};
            app.btnEstatusFiltroPSD.Text = '';
            app.CompararDropDownLabel = uilabel(app.FrecuenciaTab);
            app.CompararDropDownLabel.Text = 'Comparar';
            app.CompararDropDown_Frecuencia = uidropdown(app.FrecuenciaTab);
            app.CompararDropDown_Frecuencia.Items = {'Ninguna', 'Corona', 'Tracking', 'Bad tracking', 'Tracking Insulation', 'Arco'};
            app.CompararDropDown_Frecuencia.ValueChangedFcn = createCallbackFcn(app, @CompararDropDown_FrecuenciaValueChanged, true);
            app.CompararDropDown_Frecuencia.Tooltip = {'Seleccione la señal estandar con la que desea comparar.'};
            app.CompararDropDown_Frecuencia.Value = 'Ninguna';
            app.TiempoFrecuenciaTab = uitab(app.TabGroup);
            app.TiempoFrecuenciaTab.Title = 'Tiempo & Frecuencia';
            app.figFrecuenciaChannelL_2 = uiaxes(app.TiempoFrecuenciaTab);
            title(app.figFrecuenciaChannelL_2, 'Canal Izquierdo')
            xlabel(app.figFrecuenciaChannelL_2, 'Frecuencia [Hz]')
            ylabel(app.figFrecuenciaChannelL_2, 'Magnitud [dB]')
            app.figTiempoChannelL_2 = uiaxes(app.TiempoFrecuenciaTab);
            title(app.figTiempoChannelL_2, 'Canal Izquierdo')
            xlabel(app.figTiempoChannelL_2, 'Tiempo [s]')
            ylabel(app.figTiempoChannelL_2, 'Amplitud [%]')
            app.btnImpresoraTiempoyFrecuencia = uibutton(app.TiempoFrecuenciaTab, 'push');
            app.btnImpresoraTiempoyFrecuencia.ButtonPushedFcn = createCallbackFcn(app, @btnImpresoraTiempoyFrecuenciaButtonPushed, true);
            app.btnImpresoraTiempoyFrecuencia.Icon = 'impresora.png';
            app.btnImpresoraTiempoyFrecuencia.Text = '';
            app.btnPlay = uibutton(app.AnalizadorderuidoV18UIFigure, 'push');
            app.btnPlay.ButtonPushedFcn = createCallbackFcn(app, @btnPlayButtonPushed, true);
            app.btnPlay.Icon = 'play.png';
            app.btnPlay.Tooltip = {'Oprima este botón para reproducir el audio cargado.'};
            app.btnPlay.Text = '';
            app.btnStop = uibutton(app.AnalizadorderuidoV18UIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Icon = 'stop.png';
            app.btnStop.Tooltip = {'Oprima este botón para terminar la reproducción del audio cargado'};
            app.btnStop.Text = '';
            app.btnLimpieza = uibutton(app.AnalizadorderuidoV18UIFigure, 'push');
            app.btnLimpieza.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiezaButtonPushed, true);
            app.btnLimpieza.Icon = 'escoba_mano.png';
            app.btnLimpieza.Tooltip = {'Oprima este botón para limpiar el programa del actual audio.'};
            app.btnLimpieza.Text = '';
            % Show the figure after all components are created
            app.AnalizadorderuidoV18UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalizadorderuidoV18UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalizadorderuidoV18UIFigure)
        end
    end
end