classdef HMI_proyecto_intermedio_robotica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UNIVERSIDADNACIONALDECOLOMBIALabel  matlab.ui.control.Label
        SEDEBOGOTALabel                 matlab.ui.control.Label
        Robotica2020IILabel             matlab.ui.control.Label
        Image_logo                      matlab.ui.control.Image
        lbl_estado                      matlab.ui.control.Label
        img_estado                      matlab.ui.control.Image
        btn_start                       matlab.ui.control.Button
        btn_stop                        matlab.ui.control.Button
        img_grupo                       matlab.ui.container.TabGroup
        normal                          matlab.ui.container.Tab
        imagen_normal                   matlab.ui.control.UIAxes
        Button                          matlab.ui.control.StateButton
        img_deteccion                   matlab.ui.container.Tab
        grupo_posicion                  matlab.ui.container.TabGroup
        leer_posicion                   matlab.ui.container.Tab
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        POSICIONDELTOOLDELROBOTIZQUIERDOLabel  matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        POSICIONDELTOOLDELROBOTDERECHOLabel  matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        posicion_x_R_izquierda          matlab.ui.control.NumericEditField
        posicion_y_R_izquierda          matlab.ui.control.NumericEditField
        posicion_z_R_izquierda          matlab.ui.control.NumericEditField
        posicion_x_R_derecha            matlab.ui.control.NumericEditField
        posicion_y_R_derecha            matlab.ui.control.NumericEditField
        posicion_z_R_derecha            matlab.ui.control.NumericEditField
        control_posicion                matlab.ui.container.Tab
        XLabel_3                        matlab.ui.control.Label
        CONFIGURACIONLabel_4            matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        ZLabel_3                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        btn_enviar_posicion_R_izquierdo  matlab.ui.control.Button
        input_x_R_z                     matlab.ui.control.NumericEditField
        input_y_R_z                     matlab.ui.control.NumericEditField
        input_z_R_z                     matlab.ui.control.NumericEditField
        input_theta_R_z                 matlab.ui.control.NumericEditField
        CodoLabel                       matlab.ui.control.Label
        input_codo_R_z                  matlab.ui.control.NumericEditField
        btn_gripper_open_izquierdo      matlab.ui.control.Button
        btn_gripper_close_izquierdo     matlab.ui.control.Button
        Controlar_R_DerechoTab_2        matlab.ui.container.Tab
        XLabel_4                        matlab.ui.control.Label
        CONFIGURACIONLabel_5            matlab.ui.control.Label
        YLabel_4                        matlab.ui.control.Label
        ZLabel_4                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        btn_enviar_posicion_R_derecho   matlab.ui.control.Button
        input_x_R_d                     matlab.ui.control.NumericEditField
        input_y_R_d                     matlab.ui.control.NumericEditField
        input_z_R_d                     matlab.ui.control.NumericEditField
        input_theta_R_d                 matlab.ui.control.NumericEditField
        CodoLabel_2                     matlab.ui.control.Label
        input_codo_R_d                  matlab.ui.control.NumericEditField
        btn_gripper_open_derecho        matlab.ui.control.Button
        btn_gripper_close_derecho       matlab.ui.control.Button
        grupo_configuracion             matlab.ui.container.TabGroup
        leer_configuracion              matlab.ui.container.Tab
        Q1Label                         matlab.ui.control.Label
        CONFIGURACIONROBOTIZQUIERDOLabel  matlab.ui.control.Label
        Q2Label                         matlab.ui.control.Label
        Q3Label                         matlab.ui.control.Label
        Q4Label                         matlab.ui.control.Label
        Q1Label_4                       matlab.ui.control.Label
        CONFIGURACIONROBOTDERECHOLabel  matlab.ui.control.Label
        Q2Label_4                       matlab.ui.control.Label
        Q3Label_4                       matlab.ui.control.Label
        Q4Label_4                       matlab.ui.control.Label
        config_q1_Iz                    matlab.ui.control.NumericEditField
        config_q2_Iz                    matlab.ui.control.NumericEditField
        config_q3_Iz                    matlab.ui.control.NumericEditField
        config_q4_Iz                    matlab.ui.control.NumericEditField
        config_q1_De                    matlab.ui.control.NumericEditField
        config_q2_De                    matlab.ui.control.NumericEditField
        config_q3_De                    matlab.ui.control.NumericEditField
        config_q4_De                    matlab.ui.control.NumericEditField
        controlar_configuracion         matlab.ui.container.Tab
        Q1Label_2                       matlab.ui.control.Label
        CONFIGURACIONLabel_2            matlab.ui.control.Label
        Q2Label_2                       matlab.ui.control.Label
        Q3Label_2                       matlab.ui.control.Label
        Q4Label_2                       matlab.ui.control.Label
        btn_enviar_R_izquierdo          matlab.ui.control.Button
        input_q1_R_z                    matlab.ui.control.NumericEditField
        input_q2_R_z                    matlab.ui.control.NumericEditField
        input_q3_R_z                    matlab.ui.control.NumericEditField
        input_q4_R_z                    matlab.ui.control.NumericEditField
        Controlar_R_DerechoTab          matlab.ui.container.Tab
        CONFIGURACIONLabel_3            matlab.ui.control.Label
        Q1Label_3                       matlab.ui.control.Label
        input_q1_R_d                    matlab.ui.control.NumericEditField
        Q2Label_3                       matlab.ui.control.Label
        input_q2_R_d                    matlab.ui.control.NumericEditField
        Q3Label_3                       matlab.ui.control.Label
        input_q3_R_d                    matlab.ui.control.NumericEditField
        Q4Label_3                       matlab.ui.control.Label
        input_q4_R_d                    matlab.ui.control.NumericEditField
        btn_enviar_R_derecho            matlab.ui.control.Button
    end

    
    properties (Access = public)
        publicador_robot_izquierda_joint_1; % Publicador robot izquerdo articulacion 1.
        publicador_robot_izquierda_joint_2; % Publicador robot izquerdo articulacion 2.
        publicador_robot_izquierda_joint_3; % Publicador robot izquerdo articulacion 3.
        publicador_robot_izquierda_joint_4; % Publicador robot izquerdo articulacion 4.
        publicador_robot_izquierda_gripper; % Publicador robot izquerdo gripper.
        robot_izquierda_articulacion_1;     % mensaje de la articulacion 1 del robot izquierdo.
        robot_izquierda_articulacion_2;     % mensaje de la articulacion 2 del robot izquierdo.
        robot_izquierda_articulacion_3;     % mensaje de la articulacion 3 del robot izquierdo.
        robot_izquierda_articulacion_4;     % mensaje de la articulacion 4 del robot izquierdo.
        robot_izquierda_gripper;            % Mensaje del gripper del robot izquierdo.
        publicador_robot_derecha_joint_1;   % Publicador robot derecha articulacion 1.
        publicador_robot_derecha_joint_2;   % Publicador robot derecha articulacion 2.
        publicador_robot_derecha_joint_3;   % Publicador robot derecha articulacion 3.
        publicador_robot_derecha_joint_4;   % Publicador robot derecha articulacion 4.
        publicador_robot_derecha_gripper;   % Publicador al gripper del robot derecho.
        robot_derecha_articulacion_1;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_2;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_3;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_4;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_gripper;              % Mensaje del gripper del robot derecho.
        suscriptor_R_izquierdo;             % Suscriptor para leer la configuracion del robot izquierdo.
        suscriptor_R_derecho;               % Suscriptor para leer la configuracion del robot derecho.
        robot;                              % Variable para la creacion del robot virtual.
        q1_R_izquierda;                     % Variable para almacenar el valor de la articulacion q1 del robot de la izquierda.
        q2_R_izquierda;                     % Variable para almacenar el valor de la articulacion q2 del robot de la izquierda.
        q3_R_izquierda;                     % Variable para almacenar el valor de la articulacion q3 del robot de la izquierda.
        q4_R_izquierda;                     % Variable para almacenar el valor de la articulacion q4 del robot de la izquierda.
        q1_R_derecha;                       % Variable para almacenar el valor de la articulacion q1 del robot de la derecha.
        q2_R_derecha;                       % Variable para almacenar el valor de la articulacion q2 del robot de la derecha.
        q3_R_derecha;                       % Variable para almacenar el valor de la articulacion q3 del robot de la derecha.
        q4_R_derecha;                       % Variable para almacenar el valor de la articulacion q4 del robot de la derecha.
        q_actual_derecho;                   % Variable para almacenar la configuracion actual del robot derecho.
        q_actual_izquierdo;                 % Variable para almacenar la configuracion actual del robot izquierdo.
        delete_service;                     % Variable para almacenar el servicio de delete de modelos de Gazebo.
        mensaje_delete;                     % Variable para almacenar el mensaje para delete model en Gazebo.
        set_service;                        % Variable para almacenar el servicio de set de los modelos.
        mensaje_set;                        % Variable para almacenar el mensaje para setear el parametro.
        fisica_service_pausa;               % Variable para almacenar el servicio de fisica de la simulacion.
        mensaje_seteo_fisica_pausa;         % Variable para almacenar el mensaje para setar la fisica de la simulacion.
        imgsus;                             % Variable para almacenar el suscriptor de la camara.
        img;                                % Variable para almacenar la variable recibida.
        ver_camara;                         % Variable Booleana para decidir si ver la camara o no;
        
    end
    
    methods (Access = public)        
        function iniciar_publicadores_robot_izquierdo(app)
            % Creacion de los publicadores del robot izquierdo:
            app.publicador_robot_izquierda_joint_1 = rospublisher('/robot_izquierda/joint1_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 1 creado...');
            
            app.publicador_robot_izquierda_joint_2 = rospublisher('/robot_izquierda/joint2_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 2 creado...');
            
            app.publicador_robot_izquierda_joint_3 = rospublisher('/robot_izquierda/joint3_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 3 creado...');
            
            app.publicador_robot_izquierda_joint_4 = rospublisher('/robot_izquierda/joint4_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 4 creado...');
            
            app.publicador_robot_izquierda_gripper = rospublisher('/robot_izquierda/gripper_position_controller/command','std_msgs/Float64MultiArray');
            pause(1);
            disp('Publicador del gripper del robot izquierdo creado...'); 
            
            %Creación del mensaje de articulaciones del robot de la izquierda:    
            app.robot_izquierda_articulacion_1 = rosmessage(app.publicador_robot_izquierda_joint_1);
            app.robot_izquierda_articulacion_2 = rosmessage(app.publicador_robot_izquierda_joint_2);
            app.robot_izquierda_articulacion_3 = rosmessage(app.publicador_robot_izquierda_joint_3);
            app.robot_izquierda_articulacion_4 = rosmessage(app.publicador_robot_izquierda_joint_4);
            app.robot_izquierda_gripper = rosmessage(app.publicador_robot_izquierda_gripper);
            app.enviar_datos_articulaciones_robot_izquierdo(0,45,-90,-45);
        end
        
        function iniciar_publicadores_robot_derecho(app)
            % Creacion de los publicadores del robot derecho:           
            app.publicador_robot_derecha_joint_1 = rospublisher('/robot_derecha/joint1_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 1 creado...');
            
            app.publicador_robot_derecha_joint_2 = rospublisher('/robot_derecha/joint2_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 2 creado...');
            
            app.publicador_robot_derecha_joint_3 = rospublisher('/robot_derecha/joint3_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 3 creado...');
            
            app.publicador_robot_derecha_joint_4 = rospublisher('/robot_derecha/joint4_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 4 creado...');
            
            app.publicador_robot_derecha_gripper = rospublisher('/robot_derecha/gripper_position_controller/command','std_msgs/Float64MultiArray');
            pause(1);
            disp('Publicador del gripper del robot derecha creado...');
            % Creacion de los mensajes del robot derecho:
            app.robot_derecha_articulacion_1 = rosmessage(app.publicador_robot_derecha_joint_1);
            app.robot_derecha_articulacion_2 = rosmessage(app.publicador_robot_derecha_joint_2);
            app.robot_derecha_articulacion_3 = rosmessage(app.publicador_robot_derecha_joint_3);
            app.robot_derecha_articulacion_4 = rosmessage(app.publicador_robot_derecha_joint_4);
            app.robot_derecha_gripper = rosmessage(app.publicador_robot_derecha_gripper);           
            app.enviar_datos_articulaciones_robot_derecho(0,45,-90,-45);
            
        end
        
        function enviar_datos_articulaciones_robot_izquierdo(app,q1,q2,q3,q4)
            
            app.robot_izquierda_articulacion_1.Data = deg2rad(q1);
            app.robot_izquierda_articulacion_2.Data = deg2rad(q2);
            app.robot_izquierda_articulacion_3.Data = deg2rad(q3);
            app.robot_izquierda_articulacion_4.Data = deg2rad(q4);
            send(app.publicador_robot_izquierda_joint_1,app.robot_izquierda_articulacion_1);
            send(app.publicador_robot_izquierda_joint_2,app.robot_izquierda_articulacion_2);
            send(app.publicador_robot_izquierda_joint_3,app.robot_izquierda_articulacion_3);
            send(app.publicador_robot_izquierda_joint_4,app.robot_izquierda_articulacion_4);
            
        end
        
        function enviar_datos_articulaciones_robot_derecho(app,q1,q2,q3,q4)
            app.robot_derecha_articulacion_1.Data = deg2rad(q1);
            app.robot_derecha_articulacion_2.Data = deg2rad(q2);
            app.robot_derecha_articulacion_3.Data = deg2rad(q3);
            app.robot_derecha_articulacion_4.Data = deg2rad(q4);
            send(app.publicador_robot_derecha_joint_1,app.robot_derecha_articulacion_1);
            send(app.publicador_robot_derecha_joint_2,app.robot_derecha_articulacion_2);
            send(app.publicador_robot_derecha_joint_3,app.robot_derecha_articulacion_3);
            send(app.publicador_robot_derecha_joint_4,app.robot_derecha_articulacion_4);
            
        end
        
        function inicializar_suscriptor_R_izquierdo(app)
            app.suscriptor_R_izquierdo = rossubscriber('/robot_izquierda/joint_states');
            pause(1);
        end
        
        function inicializar_suscriptor_R_derecha(app)
            app.suscriptor_R_derecho = rossubscriber('/robot_derecha/joint_states');
            pause(1);
        end
        
        function leer_configuracion_robot_izquierda(app)
            configuracion_robot_izquierda = receive(app.suscriptor_R_izquierdo,15);
            configuracion_robot = configuracion_robot_izquierda.Position;
            app.q1_R_izquierda = configuracion_robot(3);
            app.q2_R_izquierda = configuracion_robot(4);
            app.q3_R_izquierda = configuracion_robot(5);
            app.q4_R_izquierda = configuracion_robot(6);
            app.q_actual_izquierdo = [app.q1_R_izquierda app.q2_R_izquierda app.q3_R_izquierda app.q4_R_izquierda];
            app.config_q1_Iz.Value = rad2deg(round(configuracion_robot(3),3));
            app.config_q2_Iz.Value = rad2deg(round(configuracion_robot(4),3));
            app.config_q3_Iz.Value = rad2deg(round(configuracion_robot(5),3));
            app.config_q4_Iz.Value = rad2deg(round(configuracion_robot(6),3));            
            
        end
        
        function leer_configuracion_robot_derecho(app)
            configuracion_robot_derecha = receive(app.suscriptor_R_derecho,15);
            configuracion_robot = configuracion_robot_derecha.Position;
            app.q1_R_derecha = configuracion_robot(3);
            app.q2_R_derecha = configuracion_robot(4);
            app.q3_R_derecha = configuracion_robot(5);
            app.q4_R_derecha = configuracion_robot(6);
            app.q_actual_derecho = [app.q1_R_derecha app.q2_R_derecha app.q3_R_derecha app.q4_R_derecha];
            app.config_q1_De.Value = rad2deg(round(configuracion_robot(3),3));
            app.config_q2_De.Value = rad2deg(round(configuracion_robot(4),3));
            app.config_q3_De.Value = rad2deg(round(configuracion_robot(5),3));
            app.config_q4_De.Value = rad2deg(round(configuracion_robot(6),3));            
        end
        
        function inicializar_suscriptor_camara(app)
            app.imgsus = rossubscriber('/camera/image_data');
            app.img = receive(app.imgsus);            
            imshow(readImage(app.img),'Parent',app.imagen_normal);
        end
        
        function creacion_robot_virtual(app)
                        
            l1 = 0.135875;
            l2 = 0.107;
            l3 = 0.107;
            l4 = 0.091;


            L(1) = Link('revolute','alpha', 0,    'a',0,   'd',l1,  'offset', 0,   'modified', 'qlim',[-2*pi 2*pi]);
            L(2) = Link('revolute','alpha', pi/2, 'a',0,   'd',0,   'offset', pi/2, 'modified', 'qlim',[-2*pi 2*pi]);
            L(3) = Link('revolute','alpha', 0,    'a',l2,  'd',0,   'offset', 0, 'modified', 'qlim',[-2*pi 2*pi]);
            L(4) = Link('revolute','alpha', 0,    'a',l3,  'd',0,   'offset', 0,   'modified', 'qlim',[-2*pi 2*pi]);
                    
            
            app.robot = SerialLink(L,'name','Phantom_x');
            app.robot.tool = [0 0 1 l4;
                          1 0 0 0;
                          0 1 0 0;
                          0 0 0 1];
                        
        end
        
        function leer_posicion_tool_izquierdo(app)
            posicion_robot = app.robot.fkine([app.q1_R_izquierda,app.q2_R_izquierda,app.q3_R_izquierda,app.q4_R_izquierda]);
            app.posicion_x_R_izquierda.Value = round((-1*posicion_robot(1,end))-0.125,3);
            app.posicion_y_R_izquierda.Value = -round(posicion_robot(2,end),3);
            app.posicion_z_R_izquierda.Value = round(posicion_robot(3,end),3); 
        end
        
        function leer_posicion_tool_derecho(app)
            posicion_robot = app.robot.fkine([app.q1_R_derecha,app.q2_R_derecha,app.q3_R_derecha,app.q4_R_derecha]);
            app.posicion_x_R_derecha.Value = round(posicion_robot(1,end)+0.125,3);
            app.posicion_y_R_derecha.Value = round(posicion_robot(2,end),3);            
            app.posicion_z_R_derecha.Value = round(posicion_robot(3,end),3);
            
        end
        
        function q = solucion(~,data)
            x = data(1);
            y = data(2);
            z = data(3);
            phi = deg2rad(data(4));
            l1 = 0.135875;
            l2 = 0.107;
            l3 = 0.107;
            l4 = 0.091;
            elbow = data(5); % 1 Para codo abajo, 0 para codo arriba.
            
            q = zeros(1,4);
            q(1) = atan2(y,x);
            x_0 = sqrt(x.^2 + y.^2) - l4 * cos(phi);
            z_0 = (z-l1) - l4 * sin(phi);
            
            num = x_0.^2 + z_0.^2 - l2.^2 - l3.^2;
            den = 2*l2*l3;
            D = num./den;
            flag = (D<=1);
            
            if flag
                q(3) = atan2(-sqrt(1-D.^2),D);
                if elbow
                    q(3) = atan2(sqrt(1-D.^2),D);
                end
                
                q(2) = -pi/2 + (atan2(z_0,x_0) - atan2(l3*sin(q(3)), l2+l3*cos(q(3))));
                q(4) = phi - pi/2 - q(2) - q(3);                   
                
            else
                f = msgbox('El robot no llega a esa posicion!...', 'Error','error');
                q = [0 0 0 0];
                
            end
                        
        end
        
        function trayectoria_R_izquierda(app,x,y,z,theta,codo,q_actual,mover_modelo,modelo)
            q = app.solucion([x y z theta codo]);
            tg = jtraj(q_actual,q,125);
            if mover_modelo
                for i=1:size(tg,1)    
                    app.robot_izquierda_articulacion_1.Data = tg(i,1);
                    app.robot_izquierda_articulacion_2.Data = tg(i,2);
                    app.robot_izquierda_articulacion_3.Data = tg(i,3);
                    app.robot_izquierda_articulacion_4.Data = tg(i,4);
                    send(app.publicador_robot_izquierda_joint_1,app.robot_izquierda_articulacion_1);
                    send(app.publicador_robot_izquierda_joint_2,app.robot_izquierda_articulacion_2);
                    send(app.publicador_robot_izquierda_joint_3,app.robot_izquierda_articulacion_3);
                    send(app.publicador_robot_izquierda_joint_4,app.robot_izquierda_articulacion_4);   
                    posicion_robot = app.robot.fkine([tg(i,1),tg(i,2),tg(i,3),tg(i,4)]);
                    x = round((-1*posicion_robot(1,end))-0.125,3);
                    y = -round(posicion_robot(2,end),3);
                    z = round(posicion_robot(3,end),3);
                    app.set_model(modelo,x,y,z-0.018);
                    app.posicion_x_R_izquierda.Value = round((-1*posicion_robot(1,end))-0.125,3);
                    app.posicion_y_R_izquierda.Value = -round(posicion_robot(2,end),3);
                    app.posicion_z_R_izquierda.Value = round(posicion_robot(3,end),3);
                    app.config_q1_Iz.Value = rad2deg(tg(i,1));
                    app.config_q2_Iz.Value = rad2deg(tg(i,2));
                    app.config_q3_Iz.Value = rad2deg(tg(i,3));
                    app.config_q4_Iz.Value = rad2deg(tg(i,4));
                    if (~mod(i,50) && app.ver_camara)
                        app.pedir_imagen();
                    end 
                    pause(0.035);
                end
                
            else
                for i=1:size(tg,1)    
                    app.robot_izquierda_articulacion_1.Data = tg(i,1);
                    app.robot_izquierda_articulacion_2.Data = tg(i,2);
                    app.robot_izquierda_articulacion_3.Data = tg(i,3);
                    app.robot_izquierda_articulacion_4.Data = tg(i,4);
                    send(app.publicador_robot_izquierda_joint_1,app.robot_izquierda_articulacion_1);
                    send(app.publicador_robot_izquierda_joint_2,app.robot_izquierda_articulacion_2);
                    send(app.publicador_robot_izquierda_joint_3,app.robot_izquierda_articulacion_3);
                    send(app.publicador_robot_izquierda_joint_4,app.robot_izquierda_articulacion_4);
                    posicion_robot = app.robot.fkine([tg(i,1),tg(i,2),tg(i,3),tg(i,4)]);
                    x = round((-1*posicion_robot(1,end))-0.125,3);
                    y = -round(posicion_robot(2,end),3);
                    z = round(posicion_robot(3,end),3);                    
                    app.posicion_x_R_izquierda.Value = round((-1*posicion_robot(1,end))-0.125,3);
                    app.posicion_y_R_izquierda.Value = -round(posicion_robot(2,end),3);
                    app.posicion_z_R_izquierda.Value = round(posicion_robot(3,end),3);
                    app.config_q1_Iz.Value = rad2deg(tg(i,1));
                    app.config_q2_Iz.Value = rad2deg(tg(i,2));
                    app.config_q3_Iz.Value = rad2deg(tg(i,3));
                    app.config_q4_Iz.Value = rad2deg(tg(i,4));
                    if (~mod(i,50) && app.ver_camara)
                        app.pedir_imagen();
                    end 
                    pause(0.05);
                end                 
            end                     
            
        end
        
        function trayectoria_R_derecho(app,x,y,z,theta,codo,q_actual,mover_modelo,modelo)
            q = app.solucion([x y z theta codo]);
            tg = jtraj(q_actual,q,125);
            if mover_modelo
                for i=1:size(tg,1)    
                    app.robot_derecha_articulacion_1.Data = tg(i,1);
                    app.robot_derecha_articulacion_2.Data = tg(i,2);
                    app.robot_derecha_articulacion_3.Data = tg(i,3);
                    app.robot_derecha_articulacion_4.Data = tg(i,4);
                    send(app.publicador_robot_derecha_joint_1,app.robot_derecha_articulacion_1);
                    send(app.publicador_robot_derecha_joint_2,app.robot_derecha_articulacion_2);
                    send(app.publicador_robot_derecha_joint_3,app.robot_derecha_articulacion_3);
                    send(app.publicador_robot_derecha_joint_4,app.robot_derecha_articulacion_4);
                    posicion_robot = app.robot.fkine([tg(i,1),tg(i,2),tg(i,3),tg(i,4)]);
                    x = round(posicion_robot(1,end)+0.125,3);
                    y = round(posicion_robot(2,end),3);            
                    z = round(posicion_robot(3,end),3);
                    app.set_model(modelo,x,y,z-0.03);
                    app.posicion_x_R_derecha.Value = round(posicion_robot(1,end)+0.125,3);
                    app.posicion_y_R_derecha.Value = round(posicion_robot(2,end),3);            
                    app.posicion_z_R_derecha.Value = round(posicion_robot(3,end),3);
                    app.config_q1_De.Value = rad2deg(tg(i,1));
                    app.config_q2_De.Value = rad2deg(tg(i,2));
                    app.config_q3_De.Value = rad2deg(tg(i,3));
                    app.config_q4_De.Value = rad2deg(tg(i,4));
                    if (~mod(i,50) && app.ver_camara)
                        app.pedir_imagen();
                    end                    
                    pause(0.035);
                end                 
            else
                for i=1:size(tg,1)    
                    app.robot_derecha_articulacion_1.Data = tg(i,1);
                    app.robot_derecha_articulacion_2.Data = tg(i,2);
                    app.robot_derecha_articulacion_3.Data = tg(i,3);
                    app.robot_derecha_articulacion_4.Data = tg(i,4);
                    send(app.publicador_robot_derecha_joint_1,app.robot_derecha_articulacion_1);
                    send(app.publicador_robot_derecha_joint_2,app.robot_derecha_articulacion_2);
                    send(app.publicador_robot_derecha_joint_3,app.robot_derecha_articulacion_3);
                    send(app.publicador_robot_derecha_joint_4,app.robot_derecha_articulacion_4);
                    posicion_robot = app.robot.fkine([tg(i,1),tg(i,2),tg(i,3),tg(i,4)]);
                    x = round(posicion_robot(1,end)+0.125,3);
                    y = round(posicion_robot(2,end),3);            
                    z = round(posicion_robot(3,end),3);                    
                    app.posicion_x_R_derecha.Value = round(posicion_robot(1,end)+0.125,3);
                    app.posicion_y_R_derecha.Value = round(posicion_robot(2,end),3);            
                    app.posicion_z_R_derecha.Value = round(posicion_robot(3,end),3);
                    app.config_q1_De.Value = rad2deg(tg(i,1));
                    app.config_q2_De.Value = rad2deg(tg(i,2));
                    app.config_q3_De.Value = rad2deg(tg(i,3));
                    app.config_q4_De.Value = rad2deg(tg(i,4));
                    if (~mod(i,50) && app.ver_camara)
                        app.pedir_imagen();
                    end 
                    pause(0.05);
                end 
            end         
        end
        
        function movimiento_robot_derecho(app,x_input,y_input,z_input,theta_input,codo_input,mover_modelo,modelo)
            x = x_input-0.125;
            y = y_input;
            z = z_input;
            theta = theta_input;
            codo = codo_input;            
            app.leer_configuracion_robot_derecho();
            app.trayectoria_R_derecho(x,y,z,theta,codo,app.q_actual_derecho,mover_modelo,modelo);            
        end
        
        function movimiento_robot_izquierdo(app,x_input,y_input,z_input,theta_input,codo_input,mover_modelo,modelo)            
            x = (-1*x_input) - 0.125;
            y = -y_input;
            z = z_input;
            theta = theta_input;
            codo = codo_input;            
            app.leer_configuracion_robot_izquierda();
            app.trayectoria_R_izquierda(x,y,z,theta,codo,app.q_actual_izquierdo,mover_modelo,modelo);           
            
        end
        
        function creacion_servicios(app)
            app.delete_service = rossvcclient('/gazebo/delete_model');
            pause(1);
            app.mensaje_delete = rosmessage(app.delete_service);
            app.set_service = rossvcclient('/gazebo/set_model_state');
            pause(1);
            app.mensaje_set = rosmessage(app.set_service);
                       
        end
        
        
        function set_model(app,modelo,x,y,z)
            app.mensaje_set.ModelState.ModelName = modelo;
            app.mensaje_set.ModelState.ReferenceFrame = 'world';
            app.mensaje_set.ModelState.Pose.Position.X = x;
            app.mensaje_set.ModelState.Pose.Position.Y = y;
            app.mensaje_set.ModelState.Pose.Position.Z = z;
            envio_seteo = call(app.set_service,app.mensaje_set,'Timeout',15);
            
        end


        function pedir_imagen(app)
            app.img = receive(app.imgsus);            
            imshow(readImage(app.img),'Parent',app.imagen_normal);            
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try                                                                                 % Intenta inicializar el nodo de matlab. Si no falla:
                app.lbl_estado.Text = 'Estado: Inicializando todo.';
                startup_rvc;
                waitbar(0.15,'Inicializando el nodo de matlab, por favor espere....');          % Informa al usuario del inicio del nodo de matlab.
                
                rosinit;                                                                        % Inicializa el nodo de matlab en ros.
                waitbar(0.25,'Inicializando Publicador robot izquierdo, por favor espere....'); % Informa al usuario del inicio del nodo de matlab.
                
                app.iniciar_publicadores_robot_izquierdo();                             % Funcion que inicializa los publicadores y mensajes del robot izquierdo.
                waitbar(0.35,'Inicializando Publicador robot derecho, por favor espere....'); % Informa al usuario del inicio del nodo de matlab.
                
                app.iniciar_publicadores_robot_derecho();
                waitbar(0.40,'ya casi terminamos, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                
                waitbar(0.50,'Creando el suscriptor del robot Izquierdo, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.inicializar_suscriptor_R_izquierdo();
                
                waitbar(0.60,'Creando el suscriptor del robot Derecho, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.inicializar_suscriptor_R_derecha();
                
                waitbar(0.70,'Preparando los robots, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.leer_configuracion_robot_izquierda();
                
                waitbar(0.80,'Ya casi terminamos, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.leer_configuracion_robot_derecho();
                
                waitbar(0.85,'Inicializando el suscriptor de la camara, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.inicializar_suscriptor_camara();
                
                waitbar(0.90,'Inicializando el robot virtual, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                app.creacion_robot_virtual();
                app.leer_posicion_tool_izquierdo();
                app.leer_posicion_tool_derecho();              
                app.ver_camara = false;
                waitbar(0.95,'Inicializando los servicios de Gazebo, por favor espere....');  
                app.creacion_servicios();
                
                waitbar(1,'Inicializaciones finalizadas....');                                     % Informa al usuario del inicio del nodo de matlab.                
                close all;                                                              % Cierra todas las ventanas de Informacion.
                app.img_estado.ImageSource = 'exito.png';                               % Cambia la imagen de estado a exito.png.
                app.lbl_estado.Text = 'Estado: Listo para trabajar!';                           % Cambia el lbl de estado a Nodo creado.
                
            catch                                                                 
                close all;
                app.img_estado.ImageSource = 'warning.png';                         
                app.lbl_estado.Text = 'Estado: Algo anda mal!';              
            end
        end

        % Button pushed function: btn_start
        function btn_startButtonPushed(app, event)
            
            app.img_estado.ImageSource = 'trabajando.png';               
            app.lbl_estado.Text = 'Estado: En operacion!';
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el primer ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_1]:            
            app.movimiento_robot_derecho(0.05,-0.19,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.05,-0.19,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.05,-0.19,0.1,0,0,true,'tornillo_1');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_1');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_1');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_1',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_1]:
            app.movimiento_robot_izquierdo(-0.05,-0.19,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.05,-0.19,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.05,-0.19,0.1,-90,0,true,'tuerca_1');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_1');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_1');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_1',0,0.16,0.05);
            app.set_model('tuerca_1',0,0.16,0.04);
            app.set_model('tuerca_1',0,0.16,0.03);
            app.set_model('tuerca_1',0,0.16,0.02);
            app.set_model('tuerca_1',0,0.16,0.01);
            app.set_model('tuerca_1',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_1, tuerca_1:
            app.mensaje_delete.ModelName = 'tuerca_1';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_1';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el Segundo ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_2]:            
            app.movimiento_robot_derecho(0.125,-0.2,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.125,-0.2,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.125,-0.2,0.1,0,0,true,'tornillo_2');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_2');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_2');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_2',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_2]:
            app.movimiento_robot_izquierdo(-0.125,-0.2,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.125,-0.2,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.125,-0.2,0.1,-90,0,true,'tuerca_2');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_2');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_2');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_2',0,0.16,0.05);
            app.set_model('tuerca_2',0,0.16,0.04);
            app.set_model('tuerca_2',0,0.16,0.03);
            app.set_model('tuerca_2',0,0.16,0.02);
            app.set_model('tuerca_2',0,0.16,0.01);
            app.set_model('tuerca_2',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_2, tuerca_2:
            app.mensaje_delete.ModelName = 'tuerca_2';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_2';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            %---------------------------------------------------------------------------------------------
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el Tercer ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_3]:            
            app.movimiento_robot_derecho(0.20,-0.19,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.20,-0.19,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.20,-0.19,0.1,0,0,true,'tornillo_3');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_3');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_3');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_3',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_3]:
            app.movimiento_robot_izquierdo(-0.20,-0.19,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.20,-0.19,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.20,-0.19,0.1,-90,0,true,'tuerca_3');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_3');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_3');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_3',0,0.16,0.05);
            app.set_model('tuerca_3',0,0.16,0.04);
            app.set_model('tuerca_3',0,0.16,0.03);
            app.set_model('tuerca_3',0,0.16,0.02);
            app.set_model('tuerca_3',0,0.16,0.01);
            app.set_model('tuerca_3',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_3, tuerca_3:
            app.mensaje_delete.ModelName = 'tuerca_3';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_3';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            %---------------------------------------------------------------------------------------------
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el Cuarto ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_4]:            
            app.movimiento_robot_derecho(0.26,-0.14,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.26,-0.14,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.26,-0.14,0.1,0,0,true,'tornillo_4');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_4');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_4');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_4',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_4]:
            app.movimiento_robot_izquierdo(-0.26,-0.14,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.26,-0.14,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.26,-0.14,0.1,-90,0,true,'tuerca_4');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_4');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_4');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_4',0,0.16,0.05);
            app.set_model('tuerca_4',0,0.16,0.04);
            app.set_model('tuerca_4',0,0.16,0.03);
            app.set_model('tuerca_4',0,0.16,0.02);
            app.set_model('tuerca_4',0,0.16,0.01);
            app.set_model('tuerca_4',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_4, tuerca_4:
            app.mensaje_delete.ModelName = 'tuerca_4';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_4';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            %---------------------------------------------------------------------------------------------
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el Quinto ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_5]:            
            app.movimiento_robot_derecho(0.30,-0.07,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.30,-0.07,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.30,-0.07,0.1,0,0,true,'tornillo_5');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_5');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_5');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_5',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_5]:
            app.movimiento_robot_izquierdo(-0.30,-0.07,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.30,-0.07,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.30,-0.07,0.1,-90,0,true,'tuerca_5');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_5');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_5');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_5',0,0.16,0.05);
            app.set_model('tuerca_5',0,0.16,0.04);
            app.set_model('tuerca_5',0,0.16,0.03);
            app.set_model('tuerca_5',0,0.16,0.02);
            app.set_model('tuerca_5',0,0.16,0.01);
            app.set_model('tuerca_5',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_5, tuerca_5:
            app.mensaje_delete.ModelName = 'tuerca_5';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_5';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            %---------------------------------------------------------------------------------------------
            
            
            %---------------------------------------------------------------------------------------------
            
            app.lbl_estado.Text = 'Estado: Realizando el Sexto ensamble!';
            
            %% Primer movimiento robot derecho [tornillo_6]:            
            app.movimiento_robot_derecho(0.32,0.0,0.1,0,0,false,'no');            
            app.movimiento_robot_derecho(0.32,0.0,0.03,0,0,false,'no');
            app.btn_gripper_close_derechoButtonPushed;           
            app.movimiento_robot_derecho(0.32,0.0,0.1,0,0,true,'tornillo_6');
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,true,'tornillo_6');            
            app.movimiento_robot_derecho(0,0.16,0.03,0,0,true,'tornillo_6');            
            app.btn_gripper_open_derechoButtonPushed();            
            app.set_model('tornillo_6',0,0.16,0);            
            app.movimiento_robot_derecho(0,0.16,0.1,0,0,false,'no');            
            % Robot derecho stanby:
            app.enviar_datos_articulaciones_robot_derecho(-90,45,-90,-45);
            app.pedir_imagen();
            
            
            %% Primer movimiento robot izquierdo [tuerca_6]:
            app.movimiento_robot_izquierdo(-0.32,0.0,0.1,-90,0,false,'no');
            app.movimiento_robot_izquierdo(-0.32,0.0,0.02,-90,0,false,'no');
            app.btn_gripper_close_izquierdoButtonPushed();
            app.movimiento_robot_izquierdo(-0.32,0.0,0.1,-90,0,true,'tuerca_6');
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,true,'tuerca_6');            
            app.movimiento_robot_izquierdo(0,0.16,0.07,-90,0,true,'tuerca_6');            
            app.btn_gripper_open_izquierdoButtonPushed();
            app.set_model('tuerca_6',0,0.16,0.05);
            app.set_model('tuerca_6',0,0.16,0.04);
            app.set_model('tuerca_6',0,0.16,0.03);
            app.set_model('tuerca_6',0,0.16,0.02);
            app.set_model('tuerca_6',0,0.16,0.01);
            app.set_model('tuerca_6',0,0.16,0.005);
            app.movimiento_robot_izquierdo(0,0.16,0.1,-90,0,false,'no');
            % Robot izquierda stanby:            
            app.enviar_datos_articulaciones_robot_izquierdo(90,45,-90,-45);
            app.pedir_imagen();
            
            % Eliminacion del ensamble tornillo_6, tuerca_6:
            app.mensaje_delete.ModelName = 'tuerca_6';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.mensaje_delete.ModelName = 'tornillo_6';
            envio_delete = call(app.delete_service,app.mensaje_delete,'Timeout',20);
            app.pedir_imagen();
            %---------------------------------------------------------------------------------------------
            app.img_estado.ImageSource = 'realizado.png';               
            app.lbl_estado.Text = 'Estado: Operacion Terminada!';
            app.pedir_imagen();
            
        end

        % Button pushed function: btn_stop
        function btn_stopButtonPushed(app, event)
            close all;
            rosshutdown;                                    % Apaga el nodo de matlab.
            app.img_estado.ImageSource = 'en_espera.png';   % Cambia la imagen de estado a en espera.
            app.lbl_estado.Text = 'En espera!';             % Cambia el lbl de estado a En espera.
        end

        % Button pushed function: btn_enviar_R_izquierdo
        function btn_enviar_R_izquierdoButtonPushed(app, event)
           app.enviar_datos_articulaciones_robot_izquierdo(app.input_q1_R_z.Value,app.input_q2_R_z.Value,app.input_q3_R_z.Value,app.input_q4_R_z.Value);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end

        % Button pushed function: btn_enviar_R_derecho
        function btn_enviar_R_derechoButtonPushed(app, event)
            app.enviar_datos_articulaciones_robot_derecho(app.input_q1_R_d.Value,app.input_q2_R_d.Value,app.input_q3_R_d.Value,app.input_q4_R_d.Value);
        end

        % Callback function
        function btn_readButtonPushed(app, event)
            waitbar(0.25,'Calculando, por favor espere....');       
            app.leer_configuracion_robot_izquierda();
            waitbar(0.5,'Calculando, por favor espere....'); 
            app.leer_configuracion_robot_derecho();
            waitbar(0.75,'Calculando, por favor espere....'); 
            app.leer_posicion_tool_derecho();
            waitbar(0.95,'Ya Casi listo, por favor espere....'); 
            app.leer_posicion_tool_izquierdo(); 
            close all;
        end

        % Callback function
        function btn_read_posicionButtonPushed(app, event)
            waitbar(0.25,'Calculando, por favor espere....'); 
            app.leer_configuracion_robot_izquierda();
            waitbar(0.5,'Calculando, por favor espere....'); 
            app.leer_configuracion_robot_derecho();
            waitbar(0.75,'Calculando, por favor espere....'); 
            app.leer_posicion_tool_derecho();
            waitbar(0.95,'Ya casi listo, por favor espere....'); 
            app.leer_posicion_tool_izquierdo(); 
            close all;
        end

        % Button pushed function: btn_enviar_posicion_R_derecho
        function btn_enviar_posicion_R_derechoButtonPushed(app, event)
            x = app.input_x_R_d.Value;
            y = app.input_y_R_d.Value;
            z = app.input_z_R_d.Value;
            theta = app.input_theta_R_d.Value;
            codo = app.input_codo_R_z.Value;            
            app.movimiento_robot_derecho(x,y,z,theta,codo,false,'no');
        end

        % Button pushed function: btn_enviar_posicion_R_izquierdo
        function btn_enviar_posicion_R_izquierdoButtonPushed(app, event)
            x = app.input_x_R_z.Value;
            y = app.input_y_R_z.Value;
            z = app.input_z_R_z.Value;
            theta = app.input_theta_R_z.Value;
            codo = app.input_codo_R_z.Value;
            app.movimiento_robot_izquierdo(x,y,z,theta,codo,false,'no');
            
        end

        % Button pushed function: btn_gripper_close_izquierdo
        function btn_gripper_close_izquierdoButtonPushed(app, event)
            app.robot_izquierda_gripper.Data = [0.014,0.014];
            send(app.publicador_robot_izquierda_gripper,app.robot_izquierda_gripper);
        end

        % Button pushed function: btn_gripper_open_izquierdo
        function btn_gripper_open_izquierdoButtonPushed(app, event)
            app.robot_izquierda_gripper.Data = [0.0,0.0];
            send(app.publicador_robot_izquierda_gripper,app.robot_izquierda_gripper);
        end

        % Button pushed function: btn_gripper_open_derecho
        function btn_gripper_open_derechoButtonPushed(app, event)
            app.robot_derecha_gripper.Data = [0.0,0.0];
            send(app.publicador_robot_derecha_gripper,app.robot_derecha_gripper);
        end

        % Button pushed function: btn_gripper_close_derecho
        function btn_gripper_close_derechoButtonPushed(app, event)
            app.robot_derecha_gripper.Data = [0.015,0.015];
            send(app.publicador_robot_derecha_gripper,app.robot_derecha_gripper);

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function apagargravedadButtonPushed(app, event)
            app.gravedad_off();
        end

        % Callback function
        function encendergravedadButtonPushed(app, event)
            app.gravedad_on();
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
                app.ver_camara = true;
            else
                app.ver_camara = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UNIVERSIDADNACIONALDECOLOMBIALabel = uilabel(app.UIFigure);
            app.UNIVERSIDADNACIONALDECOLOMBIALabel.Text = 'UNIVERSIDAD NACIONAL DE COLOMBIA';
            app.SEDEBOGOTALabel = uilabel(app.UIFigure);
            app.SEDEBOGOTALabel.Text = 'SEDE BOGOTA';
            app.Robotica2020IILabel = uilabel(app.UIFigure);
            app.Robotica2020IILabel.Text = 'Robotica 2020-II';
            app.Image_logo = uiimage(app.UIFigure);
            app.Image_logo.ImageSource = 'logou.png';
            app.lbl_estado = uilabel(app.UIFigure);
            app.lbl_estado.Text = 'Estado: En espera';
            app.img_estado = uiimage(app.UIFigure);
            app.img_estado.ImageSource = 'en_espera.png';
            app.btn_start = uibutton(app.UIFigure, 'push');
            app.btn_start.ButtonPushedFcn = createCallbackFcn(app, @btn_startButtonPushed, true);
            app.btn_start.Icon = 'start.png';
            app.btn_start.Tooltip = {'Pulse aqui para iniciar el proceso'};
            app.btn_start.Text = '';
            app.btn_stop = uibutton(app.UIFigure, 'push');
            app.btn_stop.ButtonPushedFcn = createCallbackFcn(app, @btn_stopButtonPushed, true);
            app.btn_stop.Icon = 'stop.jpg';
            app.btn_stop.Tooltip = {'Pulse aqui para detener el proceso.'};
            app.btn_stop.Text = '';
            app.img_grupo = uitabgroup(app.UIFigure);
            app.normal = uitab(app.img_grupo);
            app.normal.Title = 'Normal';
            app.imagen_normal = uiaxes(app.normal);
            title(app.imagen_normal, 'VISUALIZACION DEL PROCESO')
            xlabel(app.imagen_normal, '')
            ylabel(app.imagen_normal, '')
            app.Button = uibutton(app.normal, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'camara.png';
            app.Button.Text = '';
            app.img_deteccion = uitab(app.img_grupo);
            app.img_deteccion.Title = 'Deteccion';
            app.grupo_posicion = uitabgroup(app.UIFigure);
            app.leer_posicion = uitab(app.grupo_posicion);
            app.leer_posicion.Title = 'Leer';
            app.XLabel = uilabel(app.leer_posicion);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.leer_posicion);
            app.YLabel.Text = 'Y:';
            app.POSICIONDELTOOLDELROBOTIZQUIERDOLabel = uilabel(app.leer_posicion);
            app.POSICIONDELTOOLDELROBOTIZQUIERDOLabel.Text = 'POSICION DEL TOOL DEL ROBOT IZQUIERDO:';
            app.ZLabel = uilabel(app.leer_posicion);
            app.ZLabel.Text = 'Z:';
            app.XLabel_2 = uilabel(app.leer_posicion);
            app.XLabel_2.Text = 'X:';
            app.YLabel_2 = uilabel(app.leer_posicion);
            app.YLabel_2.Text = 'Y:';
            app.POSICIONDELTOOLDELROBOTDERECHOLabel = uilabel(app.leer_posicion);
            app.POSICIONDELTOOLDELROBOTDERECHOLabel.Text = 'POSICION DEL TOOL DEL ROBOT DERECHO:';
            app.ZLabel_2 = uilabel(app.leer_posicion);
            app.ZLabel_2.Text = 'Z:';
            app.posicion_x_R_izquierda = uieditfield(app.leer_posicion, 'numeric');
            app.posicion_y_R_izquierda = uieditfield(app.leer_posicion, 'numeric');
            app.posicion_z_R_izquierda = uieditfield(app.leer_posicion, 'numeric');
            app.posicion_x_R_derecha = uieditfield(app.leer_posicion, 'numeric');
            app.posicion_y_R_derecha = uieditfield(app.leer_posicion, 'numeric');
            app.posicion_z_R_derecha = uieditfield(app.leer_posicion, 'numeric');
            app.control_posicion = uitab(app.grupo_posicion);
            app.control_posicion.Title = 'Controlar_R_Izquierdo';
            app.XLabel_3 = uilabel(app.control_posicion);
            app.XLabel_3.Text = 'X:';
            app.CONFIGURACIONLabel_4 = uilabel(app.control_posicion);
            app.CONFIGURACIONLabel_4.Text = 'CONFIGURACION:';
            app.YLabel_3 = uilabel(app.control_posicion);
            app.YLabel_3.Text = 'Y:';
            app.ZLabel_3 = uilabel(app.control_posicion);
            app.ZLabel_3.Text = 'Z:';
            app.Label = uilabel(app.control_posicion);
            app.Label.Text = 'θ:';
            app.btn_enviar_posicion_R_izquierdo = uibutton(app.control_posicion, 'push');
            app.btn_enviar_posicion_R_izquierdo.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_posicion_R_izquierdoButtonPushed, true);
            app.btn_enviar_posicion_R_izquierdo.Icon = 'enviar.png';
            app.btn_enviar_posicion_R_izquierdo.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.btn_enviar_posicion_R_izquierdo.Text = '';
            app.input_x_R_z = uieditfield(app.control_posicion, 'numeric');
            app.input_y_R_z = uieditfield(app.control_posicion, 'numeric');
            app.input_z_R_z = uieditfield(app.control_posicion, 'numeric');
            app.input_theta_R_z = uieditfield(app.control_posicion, 'numeric');
            app.CodoLabel = uilabel(app.control_posicion);
            app.CodoLabel.Text = 'Codo:';
            app.input_codo_R_z = uieditfield(app.control_posicion, 'numeric');
            app.btn_gripper_open_izquierdo = uibutton(app.control_posicion, 'push');
            app.btn_gripper_open_izquierdo.ButtonPushedFcn = createCallbackFcn(app, @btn_gripper_open_izquierdoButtonPushed, true);
            app.btn_gripper_open_izquierdo.Icon = 'open.png';
            app.btn_gripper_open_izquierdo.Text = '';
            app.btn_gripper_close_izquierdo = uibutton(app.control_posicion, 'push');
            app.btn_gripper_close_izquierdo.ButtonPushedFcn = createCallbackFcn(app, @btn_gripper_close_izquierdoButtonPushed, true);
            app.btn_gripper_close_izquierdo.Icon = 'close.png';
            app.btn_gripper_close_izquierdo.Text = '';
            app.Controlar_R_DerechoTab_2 = uitab(app.grupo_posicion);
            app.Controlar_R_DerechoTab_2.Title = 'Controlar_R_Derecho';
            app.XLabel_4 = uilabel(app.Controlar_R_DerechoTab_2);
            app.XLabel_4.Text = 'X:';
            app.CONFIGURACIONLabel_5 = uilabel(app.Controlar_R_DerechoTab_2);
            app.CONFIGURACIONLabel_5.Text = 'CONFIGURACION:';
            app.YLabel_4 = uilabel(app.Controlar_R_DerechoTab_2);
            app.YLabel_4.Text = 'Y:';
            app.ZLabel_4 = uilabel(app.Controlar_R_DerechoTab_2);
            app.ZLabel_4.Text = 'Z:';
            app.Label_2 = uilabel(app.Controlar_R_DerechoTab_2);
            app.Label_2.Text = 'θ:';
            app.btn_enviar_posicion_R_derecho = uibutton(app.Controlar_R_DerechoTab_2, 'push');
            app.btn_enviar_posicion_R_derecho.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_posicion_R_derechoButtonPushed, true);
            app.btn_enviar_posicion_R_derecho.Icon = 'enviar.png';
            app.btn_enviar_posicion_R_derecho.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.btn_enviar_posicion_R_derecho.Text = '';
            app.input_x_R_d = uieditfield(app.Controlar_R_DerechoTab_2, 'numeric');
            app.input_y_R_d = uieditfield(app.Controlar_R_DerechoTab_2, 'numeric');
            app.input_z_R_d = uieditfield(app.Controlar_R_DerechoTab_2, 'numeric');
            app.input_theta_R_d = uieditfield(app.Controlar_R_DerechoTab_2, 'numeric');
            app.CodoLabel_2 = uilabel(app.Controlar_R_DerechoTab_2);
            app.CodoLabel_2.Text = 'Codo:';
            app.input_codo_R_d = uieditfield(app.Controlar_R_DerechoTab_2, 'numeric');
            app.btn_gripper_open_derecho = uibutton(app.Controlar_R_DerechoTab_2, 'push');
            app.btn_gripper_open_derecho.ButtonPushedFcn = createCallbackFcn(app, @btn_gripper_open_derechoButtonPushed, true);
            app.btn_gripper_open_derecho.Icon = 'open.png';
            app.btn_gripper_open_derecho.Text = '';
            app.btn_gripper_close_derecho = uibutton(app.Controlar_R_DerechoTab_2, 'push');
            app.btn_gripper_close_derecho.ButtonPushedFcn = createCallbackFcn(app, @btn_gripper_close_derechoButtonPushed, true);
            app.btn_gripper_close_derecho.Icon = 'close.png';
            app.btn_gripper_close_derecho.Text = '';
            app.grupo_configuracion = uitabgroup(app.UIFigure);
            app.leer_configuracion = uitab(app.grupo_configuracion);
            app.leer_configuracion.Title = 'Leer';
            app.Q1Label = uilabel(app.leer_configuracion);
            app.Q1Label.Text = 'Q1:';
            app.CONFIGURACIONROBOTIZQUIERDOLabel = uilabel(app.leer_configuracion);
            app.CONFIGURACIONROBOTIZQUIERDOLabel.Text = 'CONFIGURACION ROBOT IZQUIERDO:';
            app.Q2Label = uilabel(app.leer_configuracion);
            app.Q2Label.Text = 'Q2:';
            app.Q3Label = uilabel(app.leer_configuracion);
            app.Q3Label.Text = 'Q3:';
            app.Q4Label = uilabel(app.leer_configuracion);
            app.Q4Label.Text = 'Q4:';
            app.Q1Label_4 = uilabel(app.leer_configuracion);
            app.Q1Label_4.Text = 'Q1:';
            app.CONFIGURACIONROBOTDERECHOLabel = uilabel(app.leer_configuracion);
            app.CONFIGURACIONROBOTDERECHOLabel.Text = 'CONFIGURACION ROBOT DERECHO:';
            app.Q2Label_4 = uilabel(app.leer_configuracion);
            app.Q2Label_4.Text = 'Q2:';
            app.Q3Label_4 = uilabel(app.leer_configuracion);
            app.Q3Label_4.Text = 'Q3:';
            app.Q4Label_4 = uilabel(app.leer_configuracion);
            app.Q4Label_4.Text = 'Q4:';
            app.config_q1_Iz = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q2_Iz = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q3_Iz = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q4_Iz = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q1_De = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q2_De = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q3_De = uieditfield(app.leer_configuracion, 'numeric');
            app.config_q4_De = uieditfield(app.leer_configuracion, 'numeric');
            app.controlar_configuracion = uitab(app.grupo_configuracion);
            app.controlar_configuracion.Title = 'Controlar_R_Izquierdo';
            app.Q1Label_2 = uilabel(app.controlar_configuracion);
            app.Q1Label_2.Text = 'Q1:';
            app.CONFIGURACIONLabel_2 = uilabel(app.controlar_configuracion);
            app.CONFIGURACIONLabel_2.Text = 'CONFIGURACION:';
            app.Q2Label_2 = uilabel(app.controlar_configuracion);
            app.Q2Label_2.Text = 'Q2:';
            app.Q3Label_2 = uilabel(app.controlar_configuracion);
            app.Q3Label_2.Text = 'Q3:';
            app.Q4Label_2 = uilabel(app.controlar_configuracion);
            app.Q4Label_2.Text = 'Q4:';
            app.btn_enviar_R_izquierdo = uibutton(app.controlar_configuracion, 'push');
            app.btn_enviar_R_izquierdo.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_R_izquierdoButtonPushed, true);
            app.btn_enviar_R_izquierdo.Icon = 'enviar.png';
            app.btn_enviar_R_izquierdo.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.btn_enviar_R_izquierdo.Text = '';
            app.input_q1_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q2_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q3_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q4_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.Controlar_R_DerechoTab = uitab(app.grupo_configuracion);
            app.Controlar_R_DerechoTab.Title = 'Controlar_R_Derecho';
            app.CONFIGURACIONLabel_3 = uilabel(app.Controlar_R_DerechoTab);
            app.CONFIGURACIONLabel_3.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.CONFIGURACIONLabel_3.Text = 'CONFIGURACION:';
            app.Q1Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q1Label_3.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q1Label_3.Text = 'Q1:';
            app.input_q1_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.input_q1_R_d.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q2Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q2Label_3.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q2Label_3.Text = 'Q2:';
            app.input_q2_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.input_q2_R_d.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q3Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q3Label_3.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q3Label_3.Text = 'Q3:';
            app.input_q3_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.input_q3_R_d.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q4Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q4Label_3.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.Q4Label_3.Text = 'Q4:';
            app.input_q4_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.input_q4_R_d.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.btn_enviar_R_derecho = uibutton(app.Controlar_R_DerechoTab, 'push');
            app.btn_enviar_R_derecho.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_R_derechoButtonPushed, true);
            app.btn_enviar_R_derecho.Icon = 'enviar.png';
            app.btn_enviar_R_derecho.Tooltip = {'Click aqui para enviar la configuracion al robot.'};
            app.btn_enviar_R_derecho.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMI_proyecto_intermedio_robotica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end